classdef GyrfalconApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GyrfalconUIFigure               matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ScanSimulationTab               matlab.ui.container.Tab
        SimulationPanel                 matlab.ui.container.Panel
        SimulationFilePathLabel         matlab.ui.control.Label
        SimulationSaveButton            matlab.ui.control.Button
        SimulationLoadButton            matlab.ui.control.Button
        SimulationSaveInSeparateFileCheckBox  matlab.ui.control.CheckBox
        ScatteringNoiseLevelEditField_2Label  matlab.ui.control.Label
        SimulationScatteringNoiseLevelEditField  matlab.ui.control.NumericEditField
        DetectorNoiseLevelEditField_2Label  matlab.ui.control.Label
        SimulationDetectorNoiseLevelEditField  matlab.ui.control.NumericEditField
        PartialPixelResolutionEditField_2Label  matlab.ui.control.Label
        SimulationPartialPixelResolutionEditField  matlab.ui.control.NumericEditField
        SimulationEnablePartialPixelModellingCheckBox  matlab.ui.control.CheckBox
        PhantomPanel                    matlab.ui.container.Panel
        PhantomFilePathLabel            matlab.ui.control.Label
        PhantomSaveButton               matlab.ui.control.Button
        PhantomLoadButton               matlab.ui.control.Button
        PhantomSaveInSeparateFileCheckBox  matlab.ui.control.CheckBox
        PhantomLocationmLeftmostXForemostYTopZLabel  matlab.ui.control.Label
        PhantomCentreAboutOriginButton  matlab.ui.control.Button
        VoxelDimensionsmmLabel          matlab.ui.control.Label
        xEditFieldLabel                 matlab.ui.control.Label
        PhantomLocationXEditField       matlab.ui.control.NumericEditField
        yEditFieldLabel                 matlab.ui.control.Label
        PhantomLocationYEditField       matlab.ui.control.NumericEditField
        zEditFieldLabel                 matlab.ui.control.Label
        PhantomLocationZEditField       matlab.ui.control.NumericEditField
        xEditField_2Label               matlab.ui.control.Label
        PhantomVoxelDimsXEditField      matlab.ui.control.NumericEditField
        yEditField_2Label               matlab.ui.control.Label
        PhantomVoxelDimsYEditField      matlab.ui.control.NumericEditField
        zEditField_2Label               matlab.ui.control.Label
        PhantomVoxelDimsZEditField      matlab.ui.control.NumericEditField
        PhantomVoxelDataSetPanel        matlab.ui.container.Panel
        PhantomDataSetFilePathLabel     matlab.ui.control.Label
        PhantomDataSetNewButton         matlab.ui.control.Button
        PhantomDataSetLoadButton        matlab.ui.control.Button
        PhantomDataSetSaveInSeparateFileCheckBox  matlab.ui.control.CheckBox
        PhantomDataSetImportMatrixButton  matlab.ui.control.Button
        DataSetDimensionsEditFieldLabel  matlab.ui.control.Label
        PhantomDataSetDimensionsEditField  matlab.ui.control.EditField
        SourcePanel                     matlab.ui.container.Panel
        SourceFilePathLabel             matlab.ui.control.Label
        SourceSaveButton                matlab.ui.control.Button
        SourceLoadButton                matlab.ui.control.Button
        SourceSaveInSeparateFileCheckBox  matlab.ui.control.CheckBox
        StartingLocationmLabel          matlab.ui.control.Label
        xEditField_3Label               matlab.ui.control.Label
        SourceLocationXEditField        matlab.ui.control.NumericEditField
        yEditField_3Label               matlab.ui.control.Label
        SourceLocationYEditField        matlab.ui.control.NumericEditField
        SourceDimensionsLabel           matlab.ui.control.Label
        xyEditFieldLabel                matlab.ui.control.Label
        SourceDimsXYEditField           matlab.ui.control.NumericEditField
        zEditField_3Label               matlab.ui.control.Label
        SourceDimsZEditField            matlab.ui.control.NumericEditField
        SourceDimsZUnitsDropDown        matlab.ui.control.DropDown
        SourceDimsXYUnitsDropDown       matlab.ui.control.DropDown
        BeamAngleLabel                  matlab.ui.control.Label
        xyEditField_2Label              matlab.ui.control.Label
        SourceBeamAngleXYEditField      matlab.ui.control.NumericEditField
        zEditField_4Label               matlab.ui.control.Label
        SourceBeamAngleZEditField       matlab.ui.control.NumericEditField
        SourceBeamAngleFillDetectorButton  matlab.ui.control.Button
        SourceLocationOppositeOfDetectorButton  matlab.ui.control.Button
        DetectorPanel                   matlab.ui.container.Panel
        DetectorFilePathLabel           matlab.ui.control.Label
        DetectorSaveButton              matlab.ui.control.Button
        DetectorLoadButton              matlab.ui.control.Button
        DetectorSaveInSeparateFileCheckBox  matlab.ui.control.CheckBox
        StartingLocationmLabel_2        matlab.ui.control.Label
        xEditField_4Label               matlab.ui.control.Label
        DetectorLocationXEditField      matlab.ui.control.NumericEditField
        yEditField_4Label               matlab.ui.control.Label
        DetectorLocationYEditField      matlab.ui.control.NumericEditField
        WholeDetectorDimensionsLabel    matlab.ui.control.Label
        xyEditField_3Label              matlab.ui.control.Label
        DetectorWholeDetectorDimsXYEditField  matlab.ui.control.NumericEditField
        zEditField_5Label               matlab.ui.control.Label
        DetectorWholeDetectorDimsZEditField  matlab.ui.control.NumericEditField
        DetectorPixelDimensionsLabel    matlab.ui.control.Label
        DetectorPixelDimsZUnitsDropDown  matlab.ui.control.DropDown
        DetectorPixelDimsXYUnitsDropDown  matlab.ui.control.DropDown
        xyEditField_4Label              matlab.ui.control.Label
        DetectorPixelDimsXYEditField    matlab.ui.control.NumericEditField
        zEditField_6Label               matlab.ui.control.Label
        DetectorPixelDimsZEditField     matlab.ui.control.NumericEditField
        DetectorPixelDimsXYEqualZButton  matlab.ui.control.Button
        DetectorPixelDimsZEqualXYButton  matlab.ui.control.Button
        DetectorMovesWithPerAngleStepsCheckBox  matlab.ui.control.CheckBox
        DetectorLocationOppositeOfSourceButton  matlab.ui.control.Button
        RadiusforAngularDetectormLabel  matlab.ui.control.Label
        DetectorAngularDetectorRadiusEditField  matlab.ui.control.NumericEditField
        SetRadiusToIsocentreDistanceButton  matlab.ui.control.Button
        SetRadiusToSourceDistanceButton  matlab.ui.control.Button
        DetectorMovesWithScanAngleCheckBox  matlab.ui.control.CheckBox
        ScanPanel                       matlab.ui.container.Panel
        ScanFilePathLabel               matlab.ui.control.Label
        ScanSaveButton                  matlab.ui.control.Button
        ScanLoadButton                  matlab.ui.control.Button
        ScanSaveInSeparateFileCheckBox  matlab.ui.control.CheckBox
        ScanAnglesabcorstartintervalendLabel  matlab.ui.control.Label
        ScanAnglesEditField             matlab.ui.control.EditField
        SlicePositionsmmabcorstartintervalendLabel  matlab.ui.control.Label
        ScanSlicePositionsEditField     matlab.ui.control.EditField
        PerAngleTranslationStepsLabel   matlab.ui.control.Label
        xyEditField_5Label              matlab.ui.control.Label
        ScanPerAngleTranslationXYEditField  matlab.ui.control.NumericEditField
        zEditField_7Label               matlab.ui.control.Label
        ScanPerAngleTranslationZEditField  matlab.ui.control.NumericEditField
        PerAngleStepDimensionsmmLabel   matlab.ui.control.Label
        xyEditField_6Label              matlab.ui.control.Label
        ScanPerAngleStepDimsXYEditField  matlab.ui.control.NumericEditField
        zEditField_8Label               matlab.ui.control.Label
        ScanPerAngleStepDimsZEditField  matlab.ui.control.NumericEditField
        BeamCharacterizationPanel       matlab.ui.container.Panel
        BeamCharacterizationFilePathLabel  matlab.ui.control.Label
        BeamCharacterizationSaveInSeparateFileCheckBox  matlab.ui.control.CheckBox
        BeamCharacterizationNewButton   matlab.ui.control.Button
        BeamCharacterizationLoadButton  matlab.ui.control.Button
        ScanSimulationControlPanel      matlab.ui.container.Panel
        ScanSimMockUpSimulationButton   matlab.ui.control.Button
        ScanSimRunSimulationButton      matlab.ui.control.Button
        ScanSimAbortSimulationButton    matlab.ui.control.Button
        SimulationRunPanel              matlab.ui.container.Panel
        SimulationRunSavePathLabel      matlab.ui.control.Label
        SimulationRunSavePathChangeButton  matlab.ui.control.Button
        CPUsEditFieldLabel              matlab.ui.control.Label
        SimulationRunNumCPUsEditField   matlab.ui.control.NumericEditField
        SimulationRunNumCPUsAvailableLabel  matlab.ui.control.Label
        SimulationRunAnimationListBoxLabel  matlab.ui.control.Label
        SimulationRunShowDetectorRayTracesCheckBox  matlab.ui.control.CheckBox
        SimulationRunShowDetectorValuesCheckBox  matlab.ui.control.CheckBox
        SimulationRunShowAnglesCheckBox  matlab.ui.control.CheckBox
        SimulationRunShowSlicesCheckBox  matlab.ui.control.CheckBox
        SimulationRunShowDetectorRasterCheckBox  matlab.ui.control.CheckBox
        SimulationRunShowPerAngleRasterCheckBox  matlab.ui.control.CheckBox
        SimulationRunRunPerformanceButtonGroup  matlab.ui.container.ButtonGroup
        SimulationRunLowPerformanceButton  matlab.ui.control.RadioButton
        SimulationRunHighPerformanceButton  matlab.ui.control.RadioButton
        SimulationRunHighWithMultipleCPUsButton  matlab.ui.control.RadioButton
        SimulationRunHighWithGPUButton  matlab.ui.control.RadioButton
        NotesTextAreaLabel              matlab.ui.control.Label
        SimulationRunNotesTextArea      matlab.ui.control.TextArea
        SimulationRunUseMEXCodeCheckBox  matlab.ui.control.CheckBox
        DatasetReconstructionTab        matlab.ui.container.Tab
        DataSetReconstructionControlPanel  matlab.ui.container.Panel
        DataSetReconRunReconstructionButton  matlab.ui.control.Button
        DataSetReconAbortReconstructionButton  matlab.ui.control.Button
        DataSetReconAddToQueueButton    matlab.ui.control.Button
        DataSetReconEmptyQueueButton    matlab.ui.control.Button
        DataSetReconViewQueueButton     matlab.ui.control.Button
        SimulationImagingRunInformationPanel  matlab.ui.container.Panel
        SimulationRunInfoLoadPathLabel  matlab.ui.control.Label
        StartDateTimeEditFieldLabel     matlab.ui.control.Label
        SimulationRunInfoStartDateTimeEditField  matlab.ui.control.EditField
        RunTimeEditFieldLabel           matlab.ui.control.Label
        SimulationRunInfoRunTimeEditField  matlab.ui.control.EditField
        RunPerformanceLabel             matlab.ui.control.Label
        SimulationRunInfoRunPerformanceEditField  matlab.ui.control.EditField
        ComputerArchitectureScannerSummaryLabel  matlab.ui.control.Label
        SimulationRunInfoComputerArchitectureSummaryTextArea  matlab.ui.control.TextArea
        NotesTextArea_3Label            matlab.ui.control.Label
        SimulationRunInfoNotesTextArea  matlab.ui.control.TextArea
        InterpretedScanGeometryTextAreaLabel  matlab.ui.control.Label
        SimulationRunInfoInterpretedScanGeometryTextArea  matlab.ui.control.TextArea
        SimulationRunInfoLoadSimulationButton  matlab.ui.control.Button
        LoadSimulationImagingScanintoGyrfalconLabel  matlab.ui.control.Label
        ScanSimulationtabdatawillbeoverwrittenLabel  matlab.ui.control.Label
        VersionLabel                    matlab.ui.control.Label
        SimulationRunInfoGyrfalconVersionEditField  matlab.ui.control.EditField
        DataSetReconLoadDataButton      matlab.ui.control.Button
        ReconstructionRunSettingsPanel  matlab.ui.container.Panel
        ReconstructionRunSavePathChangeButton  matlab.ui.control.Button
        ReconstructedSliceDimensionsLabel  matlab.ui.control.Label
        ReconstructedSlicePixelDimensionsmmLabel  matlab.ui.control.Label
        xEditField_5Label               matlab.ui.control.Label
        ReconstructionRunSliceDimsXEditField  matlab.ui.control.NumericEditField
        yEditField_5Label               matlab.ui.control.Label
        ReconstructionRunSliceDimsYEditField  matlab.ui.control.NumericEditField
        xEditField_6Label               matlab.ui.control.Label
        ReconstructionRunSlicePixelDimsXEditField  matlab.ui.control.NumericEditField
        yEditField_6Label               matlab.ui.control.Label
        ReconstructionRunSlicePixelDimsYEditField  matlab.ui.control.NumericEditField
        ReconstructionAlgorithmLabel    matlab.ui.control.Label
        ReconstructionRunAlgorithmDropDown  matlab.ui.control.DropDown
        Label_4                         matlab.ui.control.Label
        ReconstructedDataSetDimensionsLabel  matlab.ui.control.Label
        ReconstructedDataSetVoxelDimensionsmmLabel  matlab.ui.control.Label
        xEditField_7Label               matlab.ui.control.Label
        ReconstructionRunDataSetDimsXEditField  matlab.ui.control.NumericEditField
        yEditField_7Label               matlab.ui.control.Label
        ReconstructionRunDataSetDimsYEditField  matlab.ui.control.NumericEditField
        zEditField_9Label               matlab.ui.control.Label
        ReconstructionRunDataSetDimsZEditField  matlab.ui.control.NumericEditField
        xEditField_8Label               matlab.ui.control.Label
        ReconstructionRunDataSetVoxelDimsXEditField  matlab.ui.control.NumericEditField
        yEditField_8Label               matlab.ui.control.Label
        ReconstructionRunDataSetVoxelDimsYEditField  matlab.ui.control.NumericEditField
        zEditField_10Label              matlab.ui.control.Label
        ReconstructionRunDataSetVoxelDimsZEditField  matlab.ui.control.NumericEditField
        DInterpolationTypeDropDownLabel  matlab.ui.control.Label
        ReconstructionRun3DInterpolationTypeDropDown  matlab.ui.control.DropDown
        Label_5                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        slicesintoa3DdatasetLabel       matlab.ui.control.Label
        UsedforinterpolatingreconstructedLabel  matlab.ui.control.Label
        ReconstructionRunNumCPUsAvailableLabel  matlab.ui.control.Label
        CPUsEditField_2Label            matlab.ui.control.Label
        ReconstructionRunNumCPUsEditField  matlab.ui.control.NumericEditField
        ReconstructionAlgorithmSettingsTabGroup  matlab.ui.container.TabGroup
        Gen1FBP_SettingsTab             matlab.ui.container.Tab
        FilterTypeLabel                 matlab.ui.control.Label
        Gen1FBP_FilterTypeDropDown      matlab.ui.control.DropDown
        ForNoiseReductionLabel          matlab.ui.control.Label
        Gen1FBP_ApplyRampRamLakFilterCheckBox  matlab.ui.control.CheckBox
        WeightsdatainFourierSpaceLabel  matlab.ui.control.Label
        Gen1FBP_ApplyBandlimitingofHighFrequenciesCheckBox  matlab.ui.control.CheckBox
        BasedonDetectorPixelSpacingSizeLabel  matlab.ui.control.Label
        Gen1FBP_SaveSinogramsandReconstructionVideosCheckBox  matlab.ui.control.CheckBox
        WeightsdatainFourierSpaceLabel_2  matlab.ui.control.Label
        ForfindingvaluesbetweendetectorcentresLabel  matlab.ui.control.Label
        InterpolationTypeDropDownLabel  matlab.ui.control.Label
        Gen1FBP_InterpolationTypeDropDown  matlab.ui.control.DropDown
        Gen1PAIR_SettingsTab            matlab.ui.container.Tab
        Gen1PAIR_UsePreComputedAlphaMatrixCheckBox  matlab.ui.control.CheckBox
        Gen1PAIR_UseNonPartialVoxelWeightingCheckBox  matlab.ui.control.CheckBox
        NonPartialVoxelWeightingCutoff01Label  matlab.ui.control.Label
        Gen1PAIR_NonPartialVoxelWeightingCutoffEditField  matlab.ui.control.NumericEditField
        Label_8                         matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        ConeBeamFDK_SettingsTab         matlab.ui.container.Tab
        CBCT_FDK_ParkerCheckBox         matlab.ui.control.CheckBox
        FilterTypeLabel_2               matlab.ui.control.Label
        CBCT_FDK_FilterDropDown         matlab.ui.control.DropDown
        ConeBeamPAIR_SettingsTab        matlab.ui.container.Tab
        RayTraceMatricesLabel           matlab.ui.control.Label
        CBCT_PAIR_RayTraceMatricesSavePathEditField  matlab.ui.control.EditField
        CBCT_PAIR_RayTraceMatricesLoadFromButton  matlab.ui.control.Button
        CBCT_PAIR_RayTraceMatricesSaveToButton  matlab.ui.control.Button
        AlphaandSelectionMatricesLabel  matlab.ui.control.Label
        CBCT_PAIR_AlphaMatricesSavePathEditField  matlab.ui.control.EditField
        CBCT_PAIR_AlphaMatricesLoadFromButton  matlab.ui.control.Button
        CBCT_PAIR_AlphaMatricesSaveToButton  matlab.ui.control.Button
        NumberofPartitionsinFinalIterationLabel  matlab.ui.control.Label
        CBCT_PAIR_NumberPartitionsEditField  matlab.ui.control.NumericEditField
        UsediflargeAlphamatricescannotfitinmemoryLabel  matlab.ui.control.Label
        Numberof2xStepUpIterationsLabel  matlab.ui.control.Label
        CBCT_PAIR_NumberIterationsEditField  matlab.ui.control.NumericEditField
        UsediflargeAlphamatricescannotfitinmemoryLabel_2  matlab.ui.control.Label
        NumberofAveragesLabel           matlab.ui.control.Label
        CBCT_PAIR_NumberAveragesEditField  matlab.ui.control.NumericEditField
        UsediflargeAlphamatricescannotfitinmemoryLabel_3  matlab.ui.control.Label
        NumberofRaysinMatrixComputeBatchLabel  matlab.ui.control.Label
        CBCT_PAIR_NumberRaysInBatchEditField  matlab.ui.control.NumericEditField
        UsediflargeAlphamatricescannotfitinmemoryLabel_4  matlab.ui.control.Label
        UsediflargeAlphamatricescannotfitinmemoryLabel_5  matlab.ui.control.Label
        UsediflargeAlphamatricescannotfitinmemoryLabel_6  matlab.ui.control.Label
        ConeBeamOSSARTSettingsTab       matlab.ui.container.Tab
        InitialImageLabel               matlab.ui.control.Label
        CBCT_OSSART_InitialImageDropDown  matlab.ui.control.DropDown
        ForwardProjectionAccuracyLabel  matlab.ui.control.Label
        CBCT_OSSART_ForwardProjectionAccuracyEditField  matlab.ui.control.NumericEditField
        NumberofIterationsLabel         matlab.ui.control.Label
        CBCT_OSSART_NumberOfIterationsEditField  matlab.ui.control.NumericEditField
        BlockSizeLabel                  matlab.ui.control.Label
        CBCT_OSSART_BlockSizeEditField  matlab.ui.control.NumericEditField
        LambdaLabel                     matlab.ui.control.Label
        CBCT_OSSART_LambdaEditField     matlab.ui.control.NumericEditField
        LambdaReductionLabel            matlab.ui.control.Label
        CBCT_OSSART_LambdaReductionEditField  matlab.ui.control.NumericEditField
        OrderStrategyLabel              matlab.ui.control.Label
        CBCT_OSSART_OrderStrategyDropDown  matlab.ui.control.DropDown
        CBCT_OSSART_VerboseCheckBox     matlab.ui.control.CheckBox
        CBCT_OSSART_RayRejectionCheckBox  matlab.ui.control.CheckBox
        ConeBeamOSASDPOCSSettingsTab    matlab.ui.container.Tab
        CBCT_OSASDPOCS_VerboseCheckBox  matlab.ui.control.CheckBox
        ForwardProjectionAccuracyLabel_2  matlab.ui.control.Label
        CBCT_OSASDPOCS_ForwardProjectionAccuracyEditField  matlab.ui.control.NumericEditField
        NumberofIterationsLabel_2       matlab.ui.control.Label
        CBCT_OSASDPOCS_NumberOfIterationsEditField  matlab.ui.control.NumericEditField
        BlockSizeLabel_2                matlab.ui.control.Label
        CBCT_OSASDPOCS_InitialBlockSizeEditField  matlab.ui.control.NumericEditField
        LambdaLabel_2                   matlab.ui.control.Label
        CBCT_OSASDPOCS_LambdaEditField  matlab.ui.control.NumericEditField
        LambdaReductionLabel_2          matlab.ui.control.Label
        CBCT_OSASDPOCS_LambdaReductionEditField  matlab.ui.control.NumericEditField
        OrderStrategyLabel_2            matlab.ui.control.Label
        CBCT_OSASDPOCS_OrderStrategyDropDown  matlab.ui.control.DropDown
        NumberofTVIterationsLabel       matlab.ui.control.Label
        CBCT_OSASDPOCS_NumberOfTvIterationsEditField  matlab.ui.control.NumericEditField
        MaximumL2ErrorRatioLabel        matlab.ui.control.Label
        CBCT_OSASDPOCS_MaxL2ErrorRatioEditField  matlab.ui.control.NumericEditField
        AlphaLabel                      matlab.ui.control.Label
        CBCT_OSASDPOCS_AlphaEditField   matlab.ui.control.NumericEditField
        AlphaReductionLabel             matlab.ui.control.Label
        CBCT_OSASDPOCS_AlphaReductionEditField  matlab.ui.control.NumericEditField
        UpdateRatioLabel                matlab.ui.control.Label
        CBCT_OSASDPOCS_UpdateRatioEditField  matlab.ui.control.NumericEditField
        ConeBeamSARTSettingsTab         matlab.ui.container.Tab
        CBCT_SART_ForwardProjectionAccuracyLabel  matlab.ui.control.Label
        CBCT_SART_ForwardProjectionAccuracyEditField  matlab.ui.control.NumericEditField
        CBCT_SART_VerboseCheckBox       matlab.ui.control.CheckBox
        CBCT_SART_RayRejectionCheckBox  matlab.ui.control.CheckBox
        InitialImageLabel_3             matlab.ui.control.Label
        CBCT_SART_InitialImageDropDown  matlab.ui.control.DropDown
        NumberofIterationsLabel_3       matlab.ui.control.Label
        CBCT_SART_NumberOfIterationsEditField  matlab.ui.control.NumericEditField
        LambdaLabel_3                   matlab.ui.control.Label
        CBCT_SART_LambdaEditField       matlab.ui.control.NumericEditField
        LambdaReductionLabel_3          matlab.ui.control.Label
        CBCT_SART_LambdaReductionEditField  matlab.ui.control.NumericEditField
        OrderStrategyLabel_3            matlab.ui.control.Label
        CBCT_SART_OrderStrategyDropDown  matlab.ui.control.DropDown
        ConeBeamSIRTSettingsTab         matlab.ui.container.Tab
        CBCT_SART_ForwardProjectionAccuracyLabel_2  matlab.ui.control.Label
        CBCT_SIRT_ForwardProjectionAccuracyEditField  matlab.ui.control.NumericEditField
        CBCT_SIRT_VerboseCheckBox       matlab.ui.control.CheckBox
        InitialImageLabel_4             matlab.ui.control.Label
        CBCT_SIRT_InitialImageDropDown  matlab.ui.control.DropDown
        NumberofIterationsLabel_4       matlab.ui.control.Label
        CBCT_SIRT_NumberOfIterationsEditField  matlab.ui.control.NumericEditField
        LambdaLabel_4                   matlab.ui.control.Label
        CBCT_SIRT_LambdaEditField       matlab.ui.control.NumericEditField
        LambdaReductionLabel_4          matlab.ui.control.Label
        CBCT_SIRT_LambdaReductionEditField  matlab.ui.control.NumericEditField
        CBCT_SIRT_RayRejectionCheckBox  matlab.ui.control.CheckBox
        ConeBeamSARTTVSettingsTab       matlab.ui.container.Tab
        CBCT_SARTTV_VerboseCheckBox     matlab.ui.control.CheckBox
        CBCT_SARTTV_RayRejectionCheckBox  matlab.ui.control.CheckBox
        InitialImageLabel_5             matlab.ui.control.Label
        CBCT_SARTTV_InitialImageDropDown  matlab.ui.control.DropDown
        ForwardProjectionAccuracyLabel_3  matlab.ui.control.Label
        CBCT_SARTTV_ForwardProjectionAccuracyEditField  matlab.ui.control.NumericEditField
        NumberofIterationsLabel_5       matlab.ui.control.Label
        CBCT_SARTTV_NumberOfIterationsEditField  matlab.ui.control.NumericEditField
        LambdaLabel_5                   matlab.ui.control.Label
        CBCT_SARTTV_LambdaEditField     matlab.ui.control.NumericEditField
        LambdaReductionLabel_5          matlab.ui.control.Label
        CBCT_SARTTV_LambdaReductionEditField  matlab.ui.control.NumericEditField
        OrderStrategyLabel_4            matlab.ui.control.Label
        CBCT_SARTTV_OrderStrategyDropDown  matlab.ui.control.DropDown
        NumberofTVIterationsLabel_2     matlab.ui.control.Label
        CBCT_SARTTV_NumberOfTvIterationsEditField  matlab.ui.control.NumericEditField
        TVLambdaLabel                   matlab.ui.control.Label
        CBCT_SARTTV_TvLambdaEditField   matlab.ui.control.NumericEditField
        ConeBeamMLEMSettingsTab         matlab.ui.container.Tab
        CBCT_MLEM_RayRejectionCheckBox  matlab.ui.control.CheckBox
        ForwardProjectionAccuracyLabel_4  matlab.ui.control.Label
        CBCT_MLEM_ForwardProjectionAccuracyEditField  matlab.ui.control.NumericEditField
        NumberofIterationsLabel_6       matlab.ui.control.Label
        CBCT_MLEM_NumberOfIterationsEditField  matlab.ui.control.NumericEditField
        ConeBeamCGLSSettingsTab         matlab.ui.container.Tab
        ForwardProjectionAccuracyLabel_5  matlab.ui.control.Label
        CBCT_CGLS_ForwardProjectionAccuracyEditField  matlab.ui.control.NumericEditField
        NumberofIterationsLabel_7       matlab.ui.control.Label
        CBCT_CGLS_NumberOfIterationsEditField  matlab.ui.control.NumericEditField
        CBCT_CGLS_VerboseCheckBox       matlab.ui.control.CheckBox
        CBCT_CGLS_RayRejectionCheckBox  matlab.ui.control.CheckBox
        InitialImageLabel_6             matlab.ui.control.Label
        CBCT_CGLS_InitialImageDropDown  matlab.ui.control.DropDown
        ConeBeamOSCTVSettingsTab        matlab.ui.container.Tab
        ForwardProjectionAccuracyLabel_6  matlab.ui.control.Label
        CBCT_OSCTV_ForwardProjectionAccuracyEditField  matlab.ui.control.NumericEditField
        NumberofIterationsLabel_8       matlab.ui.control.Label
        CBCT_OSCTV_NumberOfIterationsEditField  matlab.ui.control.NumericEditField
        InitialBlockSizeLabel_2         matlab.ui.control.Label
        CBCT_OSCTV_InitialBlockSizeEditField  matlab.ui.control.NumericEditField
        OrderStrategyLabel_5            matlab.ui.control.Label
        CBCT_OSCTV_OrderStrategyDropDown  matlab.ui.control.DropDown
        NumberofTVIterationsLabel_3     matlab.ui.control.Label
        CBCT_OSCTV_NumberOfTvIterationsEditField  matlab.ui.control.NumericEditField
        cTVstrengthLabel                matlab.ui.control.Label
        CBCT_OSCTV_CEditField           matlab.ui.control.NumericEditField
        FinalBlockSizeLabel_2           matlab.ui.control.Label
        CBCT_OSCTV_FinalBlockSizeEditField  matlab.ui.control.NumericEditField
        BlockSizeReductionPowerLabel_2  matlab.ui.control.Label
        CBCT_OSCTV_BlockSizeReductionPowerEditField  matlab.ui.control.NumericEditField
        NotesTextArea_2Label            matlab.ui.control.Label
        ReconstructionRunNotesTextArea  matlab.ui.control.TextArea
        ReconstructionRunPerformanceButtonGroup  matlab.ui.container.ButtonGroup
        ReconstructionRunHighPerformanceButton  matlab.ui.control.RadioButton
        ReconstructionRunHighWithMultipleCPUsButton  matlab.ui.control.RadioButton
        ReconstructionRunHighWithGPUButton  matlab.ui.control.RadioButton
        ReconstructionRunSavePathEditField  matlab.ui.control.EditField
        zLabel_2                        matlab.ui.control.Label
        ReconstructionRunSliceDimsZEditField  matlab.ui.control.NumericEditField
        zLabel                          matlab.ui.control.Label
        ReconstructionRunSlicePixelDimsZEditField  matlab.ui.control.NumericEditField
        DetectorPixelDimensionsforProcessingmmLabel  matlab.ui.control.Label
        xyEditField_4Label_3            matlab.ui.control.Label
        ReconstructionRunPixelDimsXYEditField  matlab.ui.control.NumericEditField
        zEditField_6Label_3             matlab.ui.control.Label
        ReconstructionRunPixelDimsZEditField  matlab.ui.control.NumericEditField
        ReconstructionRunUseRayExclusionCheckBox  matlab.ui.control.CheckBox
        ReconstructionRunInterpolateDetectorDataCheckBox  matlab.ui.control.CheckBox
        WholeDetectorDimensionsforProcessingLabel  matlab.ui.control.Label
        xyEditField_3Label_3            matlab.ui.control.Label
        ReconstructionRunWholeDetectorDimsXYEditField  matlab.ui.control.NumericEditField
        zEditField_5Label_3             matlab.ui.control.Label
        ReconstructionRunWholeDetectorDimsZEditField  matlab.ui.control.NumericEditField
        ScanSimulationViewerTab         matlab.ui.container.Tab
        SimulationViewerAxes            matlab.ui.control.UIAxes
        SimulationViewerFilePathLabel   matlab.ui.control.Label
        SimulationViewerLoadButton      matlab.ui.control.Button
        ComputerArchitectureSummaryTextAreaLabel_2  matlab.ui.control.Label
        SimulationViewerInfoComputerArchitectureSummaryTextArea  matlab.ui.control.TextArea
        NotesTextArea_3Label_2          matlab.ui.control.Label
        SimulationViewerInfoNotesTextArea  matlab.ui.control.TextArea
        PerAngleTranslationPositionLabel  matlab.ui.control.Label
        SimulationViewerPerAngleTranslationPositionListBox  matlab.ui.control.ListBox
        ScanAngleListBoxLabel           matlab.ui.control.Label
        SimulationViewerScanAngleListBox  matlab.ui.control.ListBox
        SliceListBoxLabel               matlab.ui.control.Label
        SimulationViewerSliceListBox    matlab.ui.control.ListBox
        StartDateTimeEditFieldLabel_2   matlab.ui.control.Label
        SimulationViewerInfoStartDateTimeEditField  matlab.ui.control.EditField
        RunTimeEditFieldLabel_2         matlab.ui.control.Label
        SimulationViewerInfoRunTimeEditField  matlab.ui.control.EditField
        RunPerformanceLabel_2           matlab.ui.control.Label
        SimulationViewerInfoRunPerformanceEditField  matlab.ui.control.EditField
        GyrfalconVersionEditFieldLabel_3  matlab.ui.control.Label
        SimulationViewerInfoGyrfalconVersionEditField  matlab.ui.control.EditField
        InvertImageDisplayCheckBox      matlab.ui.control.CheckBox
        LowEditFieldLabel               matlab.ui.control.Label
        ImageContrastLowEditField       matlab.ui.control.NumericEditField
        HighEditFieldLabel              matlab.ui.control.Label
        ImageContrastHighEditField      matlab.ui.control.NumericEditField
        ContrastBoundsLabel             matlab.ui.control.Label
        SimulationViewerLoopThroughAnglesButton  matlab.ui.control.StateButton
        ImagetoDisplayButtonGroup       matlab.ui.container.ButtonGroup
        I_0Button                       matlab.ui.control.RadioButton
        IButton                         matlab.ui.control.RadioButton
        I_0IButton                      matlab.ui.control.RadioButton
        lnI_0IButton                    matlab.ui.control.RadioButton
        SettingsTab                     matlab.ui.container.Tab
        DefaultSimulationSaveFolderEditFieldLabel  matlab.ui.control.Label
        SimulationSavePathSettingEditField  matlab.ui.control.EditField
        SimulationSavePathSettingChangeButton  matlab.ui.control.Button
        DefaultSimulationReconstructionRunSaveFolderEditFieldLabel  matlab.ui.control.Label
        SimulationReconstructionRunSavePathSettingEditField  matlab.ui.control.EditField
        SimulationReconstructionRunSavePathSettingChangeButton  matlab.ui.control.Button
        DefaultImagingScanRunSaveFolderLabel  matlab.ui.control.Label
        ImagingScanRunSavePathSettingEditField  matlab.ui.control.EditField
        ImagingScanRunSavePathSettingChangeButton  matlab.ui.control.Button
        DefaultImagingScanImportFolderLabel  matlab.ui.control.Label
        ImagingScanImportPathSettingEditField  matlab.ui.control.EditField
        ImagingScanImportPathSettingChangeButton  matlab.ui.control.Button
        DefaultReconstructionPreComputedDataFolderLabel  matlab.ui.control.Label
        ReconstructionPreComputedDataPathSettingEditField  matlab.ui.control.EditField
        ReconstructionPreComputedDataPathSettingChangeButton  matlab.ui.control.Button
        ImportImagingScanTab            matlab.ui.container.Tab
        ImportImagingScanDataButton     matlab.ui.control.Button
        ImagingScanDataImportPathChangeButton  matlab.ui.control.Button
        ImagingScanDataImportPathEditField  matlab.ui.control.EditField
        DefaultSimulationSaveFolderEditFieldLabel_2  matlab.ui.control.Label
        ImagingScanRunSavePathChangeButton  matlab.ui.control.Button
        ImagingScanRunSavePathEditField  matlab.ui.control.EditField
        DefaultSimulationSaveFolderEditFieldLabel_3  matlab.ui.control.Label
        NotesTextArea_2Label_2          matlab.ui.control.Label
        ImagingScanRunNotesTextArea     matlab.ui.control.TextArea
        ImagingScanTypeLabel            matlab.ui.control.Label
        ImagingScanTypeDropDown         matlab.ui.control.DropDown
        ImportImagingScanTabGroup       matlab.ui.container.TabGroup
        OpticalCTImportSettingsTab      matlab.ui.container.Tab
        LightSourceColourLabel          matlab.ui.control.Label
        OptCtSettings_LightSourceColourDropDown  matlab.ui.control.DropDown
        XRayCTImportSettingsTab         matlab.ui.container.Tab
        GyrfalconLabel                  matlab.ui.control.Label
        ComputedTomographyScanSimulatorLabel  matlab.ui.control.Label
        ImageReconstructionToolboxLabel  matlab.ui.control.Label
        StatusOuputTextAreaLabel        matlab.ui.control.Label
        StatusOutputTextArea            matlab.ui.control.TextArea
        StatusOutputLamp                matlab.ui.control.Lamp
        OpenWorkspaceButton             matlab.ui.control.Button
        SaveWorkspaceButton             matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        DavidADeVriesBCSdaviddevriesqueensucaLabel  matlab.ui.control.Label
        QueensUniversityKingstonONCanadaLabel  matlab.ui.control.Label
        KingstonGeneralHospitalCancerCentreofSoutheasternOntarioLabel  matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
    end

    
    properties (Access = public)
        workspace % holds the GyrfalconWorkspace object
        settings % hold the Gyrfalcon settings that the user can change in the GUI
        displayFigureHandle % holds the handle for the simulation mock-up figure
        axesHandle % hold the handle the axes in the simulation mock-up figure
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app = initializeGyrfalcon(app);
        end

        % Button pushed function: BeamCharacterizationLoadButton
        function BeamCharacterizationLoadButtonPushed(app, event)
            beamCharacterizationLoadButtonCallback(app);
        end

        % Button pushed function: BeamCharacterizationNewButton
        function BeamCharacterizationNewButtonPushed(app, event)
            beamCharacterizationNewButtonCallback(app);
        end

        % Button pushed function: 
        % CBCT_PAIR_AlphaMatricesLoadFromButton
        function CBCT_PAIR_AlphaMatricesLoadFromButtonPushed(app, event)
            buttonToBold = app.CBCT_PAIR_AlphaMatricesLoadFromButton;
            buttonToUnbold = app.CBCT_PAIR_AlphaMatricesSaveToButton;
            
            alphaMatricesLoadFromButtonCallback(app);
        end

        % Button pushed function: CBCT_PAIR_AlphaMatricesSaveToButton
        function CBCT_PAIR_AlphaMatricesSaveToButtonPushed(app, event)
            alphaMatricesSaveToButtonCallback(app);
        end

        % Button pushed function: 
        % CBCT_PAIR_RayTraceMatricesLoadFromButton
        function CBCT_PAIR_RayTraceMatricesLoadFromButtonPushed(app, event)
            buttonToBold = app.CBCT_PAIR_RayTraceMatricesLoadFromButton;
            buttonToUnbold = app.CBCT_PAIR_RayTraceMatricesSaveToButton;
            
            rayTraceMatricesLoadFromButtonCallback(app, buttonToBold, buttonToUnbold);
        end

        % Button pushed function: 
        % CBCT_PAIR_RayTraceMatricesSaveToButton
        function CBCT_PAIR_RayTraceMatricesSaveToButtonPushed(app, event)
            buttonToBold = app.CBCT_PAIR_RayTraceMatricesSaveToButton;
            buttonToUnbold = app.CBCT_PAIR_RayTraceMatricesLoadFromButton;
            
            rayTraceMatricesSaveToButtonCallback(app, buttonToBold, buttonToUnbold);
        end

        % Button pushed function: 
        % DataSetReconAbortReconstructionButton
        function DataSetReconAbortReconstructionButtonPushed(app, event)
            
        end

        % Button pushed function: DataSetReconAddToQueueButton
        function DataSetReconAddToQueueButtonPushed(app, event)
            dataSetReconAddToQueueButtonCallback(app);
        end

        % Button pushed function: DataSetReconEmptyQueueButton
        function DataSetReconEmptyQueueButtonPushed(app, event)
            dataSetReconEmptyQueueButtonCallback(app);
        end

        % Button pushed function: DataSetReconLoadDataButton
        function DataSetReconLoadDataButtonPushed(app, event)
            dataSetReconLoadSimulationRunButtonCallback(app);
        end

        % Button pushed function: DataSetReconRunReconstructionButton
        function DataSetReconRunReconstructionButtonPushed(app, event)
            dataSetReconRunReconstructionButtonCallback(app);
        end

        % Button pushed function: DataSetReconViewQueueButton
        function DataSetReconViewQueueButtonPushed(app, event)
            dataSetReconViewQueueButton(app);
        end

        % Button pushed function: DetectorLoadButton
        function DetectorLoadButtonPushed(app, event)
            simulationObjectLoadButtonCallback(app, class(Detector));
        end

        % Button pushed function: 
        % DetectorLocationOppositeOfSourceButton
        function DetectorLocationOppositeOfSourceButtonPushed(app, event)
            setSource = false;
            setSourceOrDetectorOppositeButtonCallback(app, setSource);
        end

        % Value changed function: 
        % DetectorMovesWithPerAngleStepsCheckBox
        function DetectorMovesWithPerAngleStepsCheckBoxValueChanged(app, event)
            value = app.DetectorMovesWithPerAngleStepsCheckBox.Value;
            
            if value %% if it's true, we need to make sure move with scan angle is checked on
                app.DetectorMovesWithScanAngleCheckBox.Value = value;
            end
        end

        % Value changed function: DetectorMovesWithScanAngleCheckBox
        function DetectorMovesWithScanAngleCheckBoxValueChanged(app, event)
            value = app.DetectorMovesWithScanAngleCheckBox.Value;
            
            if ~value %% if it's false, we need to make sure move with per angle steps is checked off
                app.DetectorMovesWithPerAngleStepsCheckBox.Value = value;
            end
        end

        % Button pushed function: DetectorPixelDimsXYEqualZButton
        function DetectorPixelDimsXYEqualZButtonPushed(app, event)
            changeXY = true;
            
            detectorMakeDetectorDimsEqualCallback(app, changeXY);
        end

        % Value changed function: DetectorPixelDimsXYUnitsDropDown
        function DetectorPixelDimsXYUnitsDropDownValueChanged(app, event)
            detectorPixelDimsUnitsDropDownCallback(app);            
        end

        % Button pushed function: DetectorPixelDimsZEqualXYButton
        function DetectorPixelDimsZEqualXYButtonPushed(app, event)
            changeXY = false;
            
            detectorMakeDetectorDimsEqualCallback(app, changeXY);
        end

        % Value changed function: DetectorPixelDimsZUnitsDropDown
        function DetectorPixelDimsZUnitsDropDownValueChanged(app, event)
            detectorPixelDimsUnitsDropDownCallback(app); 
        end

        % Button pushed function: DetectorSaveButton
        function DetectorSaveButtonPushed(app, event)
            simulationObjectSaveButtonCallback(app, class(Detector));
        end

        % Close request function: GyrfalconUIFigure
        function GyrfalconUIFigureCloseRequest(app, event)
            delete(app.displayFigureHandle);
            delete(app);
        end

        % Value changed function: ImageContrastHighEditField
        function ImageContrastHighEditFieldValueChanged(app, event)
            simulationViewerImageDisplaySettingsChangeCallback(app);  
        end

        % Value changed function: ImageContrastLowEditField
        function ImageContrastLowEditFieldValueChanged(app, event)
            simulationViewerImageDisplaySettingsChangeCallback(app);  
        end

        % Selection changed function: ImagetoDisplayButtonGroup
        function ImagetoDisplayButtonGroupSelectionChanged(app, event)
            imagetoDisplayButtonGroupCallback(app);            
        end

        % Button pushed function: 
        % ImagingScanDataImportPathChangeButton
        function ImagingScanDataImportPathChangeButtonPushed(app, event)
            imagingScanDataImportPathChangeButtonCallback(app);
        end

        % Button pushed function: 
        % ImagingScanImportPathSettingChangeButton
        function ImagingScanImportPathSettingChangeButtonPushed(app, event)
            app.settings.changeImagingScanImportPath(app);
        end

        % Button pushed function: ImagingScanRunSavePathChangeButton
        function ImagingScanRunSavePathChangeButtonPushed(app, event)
            imagingScanRunSavePathChangeButtonCallback(app);
        end

        % Button pushed function: 
        % ImagingScanRunSavePathSettingChangeButton
        function ImagingScanRunSavePathSettingChangeButtonPushed(app, event)
            app.settings.changeImagingScanRunSavePath(app);
        end

        % Value changed function: ImagingScanTypeDropDown
        function ImagingScanTypeDropDownValueChanged(app, event)
            imagingScanTypeDropDownCallback(app);            
        end

        % Button pushed function: ImportImagingScanDataButton
        function ImportImagingScanDataButtonPushed(app, event)
            importImagingScanDataButtonCallback(app);
        end

        % Value changed function: InvertImageDisplayCheckBox
        function InvertImageDisplayCheckBoxValueChanged(app, event)
            simulationViewerImageDisplaySettingsChangeCallback(app);            
        end

        % Button pushed function: OpenWorkspaceButton
        function OpenWorkspaceButtonPushed(app, event)
            openWorkspaceButtonCallback(app);
        end

        % Button pushed function: PhantomCentreAboutOriginButton
        function PhantomCentreAboutOriginButtonPushed(app, event)
            phantomCentreAboutOriginButtonCallback(app);
        end

        % Button pushed function: PhantomDataSetImportMatrixButton
        function PhantomDataSetImportMatrixButtonPushed(app, event)
            phantomDataSetImportMatrixButtonCallback(app);
        end

        % Button pushed function: PhantomDataSetLoadButton
        function PhantomDataSetLoadButtonPushed(app, event)
            phantomDataSetLoadButtonCallback(app);
        end

        % Button pushed function: PhantomDataSetNewButton
        function PhantomDataSetNewButtonPushed(app, event)
            phantomDataSetNewButtonCallback(app);
        end

        % Button pushed function: PhantomLoadButton
        function PhantomLoadButtonPushed(app, event)
            simulationObjectLoadButtonCallback(app, class(Phantom));
        end

        % Button pushed function: PhantomSaveButton
        function PhantomSaveButtonPushed(app, event)
            simulationObjectSaveButtonCallback(app, class(Phantom));
        end

        % Button pushed function: 
        % ReconstructionPreComputedDataPathSettingChangeButton
        function ReconstructionPreComputedDataPathSettingChangeButtonPushed(app, event)
            app.settings.changeReconstructionPreComputedDataPath(app);
        end

        % Value changed function: ReconstructionRunAlgorithmDropDown
        function ReconstructionRunAlgorithmDropDownValueChanged(app, event)
            reconstructionRunAlgorithmDropDownCallback(app);
        end

        % Value changed function: 
        % ReconstructionRunInterpolateDetectorDataCheckBox
        function ReconstructionRunInterpolateDetectorDataCheckBoxValueChanged(app, event)
            reconstructionRunInterpolateDetectorDataCheckBoxCallback(app);
        end

        % Selection changed function: 
        % ReconstructionRunPerformanceButtonGroup
        function ReconstructionRunPerformanceButtonGroupSelectionChanged(app, event)
            
            selectedButton = app.ReconstructionRunPerformanceButtonGroup.SelectedObject;
            
            switch selectedButton
                case app.ReconstructionRunHighPerformanceButton
                    performanceType = ReconstructionRunPerformanceTypes.high;
                case app.ReconstructionRunHighWithMultipleCPUsButton
                    performanceType = ReconstructionRunPerformanceTypes.highWithMultipleCPUs;
                case app.ReconstructionRunHighWithGPUButton
                    performanceType = ReconstructionRunPerformanceTypes.highWithGPU;
                otherwise
                    error('Invalid radio button state!');
            end
                        
            setReconstructionRunComponentEnableFromPerformanceType(app, performanceType);
            
        end

        % Button pushed function: 
        % ReconstructionRunSavePathChangeButton
        function ReconstructionRunSavePathChangeButtonPushed(app, event)
            reconstructionRunSavePathChangeButtonCallback(app);
        end

        % Button pushed function: SaveWorkspaceButton
        function SaveWorkspaceButtonPushed(app, event)
            saveWorkspaceButtonCallback(app);
        end

        % Button pushed function: ScanLoadButton
        function ScanLoadButtonPushed(app, event)
            simulationObjectLoadButtonCallback(app, class(Scan));
        end

        % Button pushed function: ScanSaveButton
        function ScanSaveButtonPushed(app, event)
            simulationObjectSaveButtonCallback(app, class(Scan));
        end

        % Button pushed function: ScanSimAbortSimulationButton
        function ScanSimAbortSimulationButtonPushed(app, event)
            
        end

        % Button pushed function: ScanSimMockUpSimulationButton
        function ScanSimMockUpSimulationButtonPushed(app, event)
            scanSimMockUpButtonCallback(app);
        end

        % Button pushed function: ScanSimRunSimulationButton
        function ScanSimRunSimulationButtonPushed(app, event)
            scanSimRunSimulationButtonCallback(app);
        end

        % Button pushed function: SetRadiusToIsocentreDistanceButton
        function SetRadiusToIsocentreDistanceButtonPushed(app, event)
            type = 'Isocentre';
            setAngularDetectorRadiusButtonCallback(app, type);
        end

        % Button pushed function: SetRadiusToSourceDistanceButton
        function SetRadiusToSourceDistanceButtonPushed(app, event)
            type = 'Source';
            setAngularDetectorRadiusButtonCallback(app, type);
        end

        % Button pushed function: SimulationLoadButton
        function SimulationLoadButtonPushed(app, event)
            simulationObjectLoadButtonCallback(app, class(Simulation));
        end

        % Button pushed function: 
        % SimulationReconstructionRunSavePathSettingChangeButton
        function SimulationReconstructionRunSavePathSettingChangeButtonPushed(app, event)
            app.settings.changeSimulationReconstructionRunSavePath(app);
        end

        % Button pushed function: 
        % SimulationRunInfoLoadSimulationButton
        function SimulationRunInfoLoadSimulationButtonPushed(app, event)
            
        end

        % Selection changed function: 
        % SimulationRunRunPerformanceButtonGroup
        function SimulationRunRunPerformanceButtonGroupSelectionChanged(app, event)
            selectedButton = app.SimulationRunRunPerformanceButtonGroup.SelectedObject;
            
            switch selectedButton
                case app.SimulationRunLowPerformanceButton
                    performanceType = SimulationRunPerformanceTypes.low;
                case app.SimulationRunHighPerformanceButton
                    performanceType = SimulationRunPerformanceTypes.high;
                case app.SimulationRunHighWithMultipleCPUsButton
                    performanceType = SimulationRunPerformanceTypes.highWithMultipleCPUs;
                case app.SimulationRunHighWithGPUButton
                    performanceType = SimulationRunPerformanceTypes.highWithGPU;
                otherwise
                    error('Invalid radio button state!');
            end
                        
            setSimulationRunComponentEnableFromPerformanceType(app, performanceType);
            
        end

        % Button pushed function: SimulationRunSavePathChangeButton
        function SimulationRunSavePathChangeButtonPushed(app, event)
            simulationRunSavePathChangeButtonCallback(app);
        end

        % Value changed function: SimulationRunShowAnglesCheckBox
        function SimulationRunShowAnglesCheckBoxValueChanged(app, event)
            value = app.SimulationRunShowAnglesCheckBox.Value;
            
            if value %if on
                app.SimulationRunShowSlicesCheckBox.Value = value;
            else % if off
                app.SimulationRunShowPerAngleRasterCheckBox.Value = value;
                app.SimulationRunShowDetectorRasterCheckBox.Value = value;
            end
            
        end

        % Value changed function: 
        % SimulationRunShowDetectorRasterCheckBox
        function SimulationRunShowDetectorRasterCheckBoxValueChanged(app, event)
            value = app.SimulationRunShowDetectorRasterCheckBox.Value;
            
            if value %if on
                app.SimulationRunShowSlicesCheckBox.Value = value;
                app.SimulationRunShowAnglesCheckBox.Value = value;
                app.SimulationRunShowPerAngleRasterCheckBox.Value = value;
            end
            
        end

        % Value changed function: 
        % SimulationRunShowPerAngleRasterCheckBox
        function SimulationRunShowPerAngleRasterCheckBoxValueChanged(app, event)
            value = app.SimulationRunShowPerAngleRasterCheckBox.Value;
            
            if value %if on
                app.SimulationRunShowSlicesCheckBox.Value = value;
                app.SimulationRunShowAnglesCheckBox.Value = value;
            else % if off
                app.SimulationRunShowDetectorRasterCheckBox.Value = value;
            end
            
        end

        % Value changed function: SimulationRunShowSlicesCheckBox
        function SimulationRunShowSlicesCheckBoxValueChanged(app, event)
            value = app.SimulationRunShowSlicesCheckBox.Value;
            
            if ~value %if off
                app.SimulationRunShowAnglesCheckBox.Value = value;
                app.SimulationRunShowPerAngleRasterCheckBox.Value = value;
                app.SimulationRunShowDetectorRasterCheckBox.Value = value;
            end
        end

        % Button pushed function: SimulationSaveButton
        function SimulationSaveButtonPushed(app, event)
            simulationObjectSaveButtonCallback(app, class(Simulation));
        end

        % Button pushed function: 
        % SimulationSavePathSettingChangeButton
        function SimulationSavePathSettingChangeButtonPushed(app, event)
            app.settings.changeSimulationSavePath(app);
        end

        % Button pushed function: SimulationViewerLoadButton
        function SimulationViewerLoadButtonPushed(app, event)
            simulationViewerLoadButtonCallback(app);
        end

        % Callback function
        function SimulationViewerLoopThroughAnglesButtonPushed(app, event)
            simulationViewerLoopThroughAnglesButtonCallback(app);
        end

        % Value changed function: 
        % SimulationViewerLoopThroughAnglesButton
        function SimulationViewerLoopThroughAnglesButtonValueChanged(app, event)
            simulationViewerLoopThroughAnglesButtonCallback(app);
        end

        % Value changed function: 
        % SimulationViewerPerAngleTranslationPositionListBox
        function SimulationViewerPerAngleTranslationPositionListBoxValueChanged(app, event)
            simulationViewerListBoxValueChangedCallback(app);            
        end

        % Value changed function: SimulationViewerScanAngleListBox
        function SimulationViewerScanAngleListBoxValueChanged(app, event)
            simulationViewerListBoxValueChangedCallback(app);            
        end

        % Value changed function: SimulationViewerSliceListBox
        function SimulationViewerSliceListBoxValueChanged(app, event)
            simulationViewerListBoxValueChangedCallback(app);            
        end

        % Button pushed function: SourceBeamAngleFillDetectorButton
        function SourceBeamAngleFillDetectorButtonPushed(app, event)
            sourceBeamAngleFillDetectorButtonCallback(app);
        end

        % Button pushed function: SourceLoadButton
        function SourceLoadButtonPushed(app, event)
            simulationObjectLoadButtonCallback(app, class(Source));
        end

        % Button pushed function: 
        % SourceLocationOppositeOfDetectorButton
        function SourceLocationOppositeOfDetectorButtonPushed(app, event)
            setSource = true;
            setSourceOrDetectorOppositeButtonCallback(app, setSource);
        end

        % Button pushed function: SourceSaveButton
        function SourceSaveButtonPushed(app, event)
            simulationObjectSaveButtonCallback(app, class(Source));
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GyrfalconUIFigure = uifigure;
            app.GyrfalconUIFigure.Name = 'Gyrfalcon';
            app.GyrfalconUIFigure.CloseRequestFcn = createCallbackFcn(app, @GyrfalconUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.GyrfalconUIFigure);
            app.ScanSimulationTab = uitab(app.TabGroup);
            app.ScanSimulationTab.Title = 'Scan Simulation';
            app.SimulationPanel = uipanel(app.ScanSimulationTab);
            app.SimulationPanel.Title = 'Simulation:';
            app.SimulationFilePathLabel = uilabel(app.SimulationPanel);
            app.SimulationFilePathLabel.Text = 'Simulation File Path';
            app.SimulationSaveButton = uibutton(app.SimulationPanel, 'push');
            app.SimulationSaveButton.ButtonPushedFcn = createCallbackFcn(app, @SimulationSaveButtonPushed, true);
            app.SimulationSaveButton.Text = 'Save';
            app.SimulationLoadButton = uibutton(app.SimulationPanel, 'push');
            app.SimulationLoadButton.ButtonPushedFcn = createCallbackFcn(app, @SimulationLoadButtonPushed, true);
            app.SimulationLoadButton.Text = 'Load';
            app.SimulationSaveInSeparateFileCheckBox = uicheckbox(app.SimulationPanel);
            app.SimulationSaveInSeparateFileCheckBox.Text = 'Save in Separate File';
            app.SimulationSaveInSeparateFileCheckBox.Value = true;
            app.ScatteringNoiseLevelEditField_2Label = uilabel(app.SimulationPanel);
            app.ScatteringNoiseLevelEditField_2Label.Text = 'Scattering Noise Level:';
            app.SimulationScatteringNoiseLevelEditField = uieditfield(app.SimulationPanel, 'numeric');
            app.SimulationScatteringNoiseLevelEditField.Limits = [0 1];
            app.SimulationScatteringNoiseLevelEditField.ValueDisplayFormat = '%11.10g';
            app.SimulationScatteringNoiseLevelEditField.Value = 0.025;
            app.DetectorNoiseLevelEditField_2Label = uilabel(app.SimulationPanel);
            app.DetectorNoiseLevelEditField_2Label.Text = 'Detector Noise Level:';
            app.SimulationDetectorNoiseLevelEditField = uieditfield(app.SimulationPanel, 'numeric');
            app.SimulationDetectorNoiseLevelEditField.Limits = [0 1];
            app.SimulationDetectorNoiseLevelEditField.ValueDisplayFormat = '%11.10g';
            app.SimulationDetectorNoiseLevelEditField.Value = 0.025;
            app.PartialPixelResolutionEditField_2Label = uilabel(app.SimulationPanel);
            app.PartialPixelResolutionEditField_2Label.Text = 'Partial Pixel Resolution:';
            app.SimulationPartialPixelResolutionEditField = uieditfield(app.SimulationPanel, 'numeric');
            app.SimulationPartialPixelResolutionEditField.Limits = [1 Inf];
            app.SimulationPartialPixelResolutionEditField.ValueDisplayFormat = '%.0f';
            app.SimulationPartialPixelResolutionEditField.Value = 1;
            app.SimulationEnablePartialPixelModellingCheckBox = uicheckbox(app.SimulationPanel);
            app.SimulationEnablePartialPixelModellingCheckBox.Text = 'Enable Partial Pixel Modelling';
            app.SimulationEnablePartialPixelModellingCheckBox.Value = true;
            app.PhantomPanel = uipanel(app.ScanSimulationTab);
            app.PhantomPanel.Title = 'Phantom:';
            app.PhantomFilePathLabel = uilabel(app.PhantomPanel);
            app.PhantomFilePathLabel.Text = 'Phantom File Path';
            app.PhantomSaveButton = uibutton(app.PhantomPanel, 'push');
            app.PhantomSaveButton.ButtonPushedFcn = createCallbackFcn(app, @PhantomSaveButtonPushed, true);
            app.PhantomSaveButton.Text = 'Save';
            app.PhantomLoadButton = uibutton(app.PhantomPanel, 'push');
            app.PhantomLoadButton.ButtonPushedFcn = createCallbackFcn(app, @PhantomLoadButtonPushed, true);
            app.PhantomLoadButton.Text = 'Load';
            app.PhantomSaveInSeparateFileCheckBox = uicheckbox(app.PhantomPanel);
            app.PhantomSaveInSeparateFileCheckBox.Text = 'Save in Separate File';
            app.PhantomSaveInSeparateFileCheckBox.Value = true;
            app.PhantomLocationmLeftmostXForemostYTopZLabel = uilabel(app.PhantomPanel);
            app.PhantomLocationmLeftmostXForemostYTopZLabel.Text = 'Phantom Location [m] (Leftmost X, Foremost Y, Top Z):';
            app.PhantomCentreAboutOriginButton = uibutton(app.PhantomPanel, 'push');
            app.PhantomCentreAboutOriginButton.ButtonPushedFcn = createCallbackFcn(app, @PhantomCentreAboutOriginButtonPushed, true);
            app.PhantomCentreAboutOriginButton.Text = 'Centre About Origin';
            app.VoxelDimensionsmmLabel = uilabel(app.PhantomPanel);
            app.VoxelDimensionsmmLabel.Text = 'Voxel Dimensions [mm]:';
            app.xEditFieldLabel = uilabel(app.PhantomPanel);
            app.xEditFieldLabel.Text = 'x:';
            app.PhantomLocationXEditField = uieditfield(app.PhantomPanel, 'numeric');
            app.PhantomLocationXEditField.ValueDisplayFormat = '%11.10g';
            app.PhantomLocationXEditField.Value = 0.025;
            app.yEditFieldLabel = uilabel(app.PhantomPanel);
            app.yEditFieldLabel.Text = 'y:';
            app.PhantomLocationYEditField = uieditfield(app.PhantomPanel, 'numeric');
            app.PhantomLocationYEditField.ValueDisplayFormat = '%11.10g';
            app.PhantomLocationYEditField.Value = 0.025;
            app.zEditFieldLabel = uilabel(app.PhantomPanel);
            app.zEditFieldLabel.Text = 'z:';
            app.PhantomLocationZEditField = uieditfield(app.PhantomPanel, 'numeric');
            app.PhantomLocationZEditField.ValueDisplayFormat = '%11.10g';
            app.PhantomLocationZEditField.Value = 0.025;
            app.xEditField_2Label = uilabel(app.PhantomPanel);
            app.xEditField_2Label.Text = 'x:';
            app.PhantomVoxelDimsXEditField = uieditfield(app.PhantomPanel, 'numeric');
            app.PhantomVoxelDimsXEditField.Limits = [0 Inf];
            app.PhantomVoxelDimsXEditField.ValueDisplayFormat = '%11.10g';
            app.PhantomVoxelDimsXEditField.Value = 0.025;
            app.yEditField_2Label = uilabel(app.PhantomPanel);
            app.yEditField_2Label.Text = 'y:';
            app.PhantomVoxelDimsYEditField = uieditfield(app.PhantomPanel, 'numeric');
            app.PhantomVoxelDimsYEditField.Limits = [0 Inf];
            app.PhantomVoxelDimsYEditField.ValueDisplayFormat = '%11.10g';
            app.PhantomVoxelDimsYEditField.Value = 0.025;
            app.zEditField_2Label = uilabel(app.PhantomPanel);
            app.zEditField_2Label.Text = 'z:';
            app.PhantomVoxelDimsZEditField = uieditfield(app.PhantomPanel, 'numeric');
            app.PhantomVoxelDimsZEditField.Limits = [0 Inf];
            app.PhantomVoxelDimsZEditField.ValueDisplayFormat = '%11.10g';
            app.PhantomVoxelDimsZEditField.Value = 0.025;
            app.PhantomVoxelDataSetPanel = uipanel(app.PhantomPanel);
            app.PhantomVoxelDataSetPanel.Title = 'Phantom Voxel Data Set:';
            app.PhantomDataSetFilePathLabel = uilabel(app.PhantomVoxelDataSetPanel);
            app.PhantomDataSetFilePathLabel.Text = 'Phantom Data Set File Path';
            app.PhantomDataSetNewButton = uibutton(app.PhantomVoxelDataSetPanel, 'push');
            app.PhantomDataSetNewButton.ButtonPushedFcn = createCallbackFcn(app, @PhantomDataSetNewButtonPushed, true);
            app.PhantomDataSetNewButton.Text = 'New';
            app.PhantomDataSetLoadButton = uibutton(app.PhantomVoxelDataSetPanel, 'push');
            app.PhantomDataSetLoadButton.ButtonPushedFcn = createCallbackFcn(app, @PhantomDataSetLoadButtonPushed, true);
            app.PhantomDataSetLoadButton.Text = 'Load';
            app.PhantomDataSetSaveInSeparateFileCheckBox = uicheckbox(app.PhantomVoxelDataSetPanel);
            app.PhantomDataSetSaveInSeparateFileCheckBox.Text = 'Save in Separate File';
            app.PhantomDataSetSaveInSeparateFileCheckBox.Value = true;
            app.PhantomDataSetImportMatrixButton = uibutton(app.PhantomVoxelDataSetPanel, 'push');
            app.PhantomDataSetImportMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @PhantomDataSetImportMatrixButtonPushed, true);
            app.PhantomDataSetImportMatrixButton.Text = 'Import Matrix';
            app.DataSetDimensionsEditFieldLabel = uilabel(app.PhantomVoxelDataSetPanel);
            app.DataSetDimensionsEditFieldLabel.Text = 'Data Set Dimensions:';
            app.PhantomDataSetDimensionsEditField = uieditfield(app.PhantomVoxelDataSetPanel, 'text');
            app.PhantomDataSetDimensionsEditField.Editable = 'off';
            app.PhantomDataSetDimensionsEditField.Value = '50x50x3';
            app.SourcePanel = uipanel(app.ScanSimulationTab);
            app.SourcePanel.Title = 'Source:';
            app.SourceFilePathLabel = uilabel(app.SourcePanel);
            app.SourceFilePathLabel.Text = 'Source File Path';
            app.SourceSaveButton = uibutton(app.SourcePanel, 'push');
            app.SourceSaveButton.ButtonPushedFcn = createCallbackFcn(app, @SourceSaveButtonPushed, true);
            app.SourceSaveButton.Text = 'Save';
            app.SourceLoadButton = uibutton(app.SourcePanel, 'push');
            app.SourceLoadButton.ButtonPushedFcn = createCallbackFcn(app, @SourceLoadButtonPushed, true);
            app.SourceLoadButton.Text = 'Load';
            app.SourceSaveInSeparateFileCheckBox = uicheckbox(app.SourcePanel);
            app.SourceSaveInSeparateFileCheckBox.Text = 'Save in Separate File';
            app.SourceSaveInSeparateFileCheckBox.Value = true;
            app.StartingLocationmLabel = uilabel(app.SourcePanel);
            app.StartingLocationmLabel.Text = 'Starting Location [m]:';
            app.xEditField_3Label = uilabel(app.SourcePanel);
            app.xEditField_3Label.Text = 'x:';
            app.SourceLocationXEditField = uieditfield(app.SourcePanel, 'numeric');
            app.SourceLocationXEditField.ValueDisplayFormat = '%11.10g';
            app.SourceLocationXEditField.Value = 0.025;
            app.yEditField_3Label = uilabel(app.SourcePanel);
            app.yEditField_3Label.Text = 'y:';
            app.SourceLocationYEditField = uieditfield(app.SourcePanel, 'numeric');
            app.SourceLocationYEditField.ValueDisplayFormat = '%11.10g';
            app.SourceLocationYEditField.Value = 0.025;
            app.SourceDimensionsLabel = uilabel(app.SourcePanel);
            app.SourceDimensionsLabel.Text = 'Source Dimensions:';
            app.xyEditFieldLabel = uilabel(app.SourcePanel);
            app.xyEditFieldLabel.Text = 'x/y:';
            app.SourceDimsXYEditField = uieditfield(app.SourcePanel, 'numeric');
            app.SourceDimsXYEditField.Limits = [0 Inf];
            app.SourceDimsXYEditField.ValueDisplayFormat = '%11.10g';
            app.SourceDimsXYEditField.Value = 0.025;
            app.zEditField_3Label = uilabel(app.SourcePanel);
            app.zEditField_3Label.Text = 'z:';
            app.SourceDimsZEditField = uieditfield(app.SourcePanel, 'numeric');
            app.SourceDimsZEditField.Limits = [0 Inf];
            app.SourceDimsZEditField.ValueDisplayFormat = '%11.10g';
            app.SourceDimsZEditField.Value = 0.025;
            app.SourceDimsZUnitsDropDown = uidropdown(app.SourcePanel);
            app.SourceDimsZUnitsDropDown.Items = {'mm', 'm', '°', 'Radians'};
            app.SourceDimsZUnitsDropDown.Value = 'mm';
            app.SourceDimsXYUnitsDropDown = uidropdown(app.SourcePanel);
            app.SourceDimsXYUnitsDropDown.Items = {'mm', 'm', '°', 'Radians'};
            app.SourceDimsXYUnitsDropDown.Value = 'mm';
            app.BeamAngleLabel = uilabel(app.SourcePanel);
            app.BeamAngleLabel.Text = 'Beam Angle [°]:';
            app.xyEditField_2Label = uilabel(app.SourcePanel);
            app.xyEditField_2Label.Text = 'x/y:';
            app.SourceBeamAngleXYEditField = uieditfield(app.SourcePanel, 'numeric');
            app.SourceBeamAngleXYEditField.Limits = [0 360];
            app.SourceBeamAngleXYEditField.ValueDisplayFormat = '%11.10g';
            app.SourceBeamAngleXYEditField.Value = 0.025;
            app.zEditField_4Label = uilabel(app.SourcePanel);
            app.zEditField_4Label.Text = 'z:';
            app.SourceBeamAngleZEditField = uieditfield(app.SourcePanel, 'numeric');
            app.SourceBeamAngleZEditField.Limits = [0 360];
            app.SourceBeamAngleZEditField.ValueDisplayFormat = '%11.10g';
            app.SourceBeamAngleZEditField.Value = 0.025;
            app.SourceBeamAngleFillDetectorButton = uibutton(app.SourcePanel, 'push');
            app.SourceBeamAngleFillDetectorButton.ButtonPushedFcn = createCallbackFcn(app, @SourceBeamAngleFillDetectorButtonPushed, true);
            app.SourceBeamAngleFillDetectorButton.Text = 'Fill Detector';
            app.SourceLocationOppositeOfDetectorButton = uibutton(app.SourcePanel, 'push');
            app.SourceLocationOppositeOfDetectorButton.ButtonPushedFcn = createCallbackFcn(app, @SourceLocationOppositeOfDetectorButtonPushed, true);
            app.SourceLocationOppositeOfDetectorButton.Text = 'Opposite of Detector';
            app.DetectorPanel = uipanel(app.ScanSimulationTab);
            app.DetectorPanel.Title = 'Detector:';
            app.DetectorFilePathLabel = uilabel(app.DetectorPanel);
            app.DetectorFilePathLabel.Text = 'Detector File Path';
            app.DetectorSaveButton = uibutton(app.DetectorPanel, 'push');
            app.DetectorSaveButton.ButtonPushedFcn = createCallbackFcn(app, @DetectorSaveButtonPushed, true);
            app.DetectorSaveButton.Text = 'Save';
            app.DetectorLoadButton = uibutton(app.DetectorPanel, 'push');
            app.DetectorLoadButton.ButtonPushedFcn = createCallbackFcn(app, @DetectorLoadButtonPushed, true);
            app.DetectorLoadButton.Text = 'Load';
            app.DetectorSaveInSeparateFileCheckBox = uicheckbox(app.DetectorPanel);
            app.DetectorSaveInSeparateFileCheckBox.Text = 'Save in Separate File';
            app.DetectorSaveInSeparateFileCheckBox.Value = true;
            app.StartingLocationmLabel_2 = uilabel(app.DetectorPanel);
            app.StartingLocationmLabel_2.Text = 'Starting Location [m]:';
            app.xEditField_4Label = uilabel(app.DetectorPanel);
            app.xEditField_4Label.Text = 'x:';
            app.DetectorLocationXEditField = uieditfield(app.DetectorPanel, 'numeric');
            app.DetectorLocationXEditField.ValueDisplayFormat = '%11.10g';
            app.DetectorLocationXEditField.Value = 0.025;
            app.yEditField_4Label = uilabel(app.DetectorPanel);
            app.yEditField_4Label.Text = 'y:';
            app.DetectorLocationYEditField = uieditfield(app.DetectorPanel, 'numeric');
            app.DetectorLocationYEditField.ValueDisplayFormat = '%11.10g';
            app.DetectorLocationYEditField.Value = 0.025;
            app.WholeDetectorDimensionsLabel = uilabel(app.DetectorPanel);
            app.WholeDetectorDimensionsLabel.Text = 'Whole Detector Dimensions [#]:';
            app.xyEditField_3Label = uilabel(app.DetectorPanel);
            app.xyEditField_3Label.Text = 'x/y:';
            app.DetectorWholeDetectorDimsXYEditField = uieditfield(app.DetectorPanel, 'numeric');
            app.DetectorWholeDetectorDimsXYEditField.Limits = [0 Inf];
            app.DetectorWholeDetectorDimsXYEditField.ValueDisplayFormat = '%.0f';
            app.DetectorWholeDetectorDimsXYEditField.Value = 1;
            app.zEditField_5Label = uilabel(app.DetectorPanel);
            app.zEditField_5Label.Text = 'z:';
            app.DetectorWholeDetectorDimsZEditField = uieditfield(app.DetectorPanel, 'numeric');
            app.DetectorWholeDetectorDimsZEditField.Limits = [0 Inf];
            app.DetectorWholeDetectorDimsZEditField.ValueDisplayFormat = '%.0f';
            app.DetectorWholeDetectorDimsZEditField.Value = 1;
            app.DetectorPixelDimensionsLabel = uilabel(app.DetectorPanel);
            app.DetectorPixelDimensionsLabel.Text = 'Detector Pixel Dimensions:';
            app.DetectorPixelDimsZUnitsDropDown = uidropdown(app.DetectorPanel);
            app.DetectorPixelDimsZUnitsDropDown.Items = {'mm', 'm', '°', 'Radians'};
            app.DetectorPixelDimsZUnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @DetectorPixelDimsZUnitsDropDownValueChanged, true);
            app.DetectorPixelDimsZUnitsDropDown.Value = 'mm';
            app.DetectorPixelDimsXYUnitsDropDown = uidropdown(app.DetectorPanel);
            app.DetectorPixelDimsXYUnitsDropDown.Items = {'mm', 'm', '°', 'Radians'};
            app.DetectorPixelDimsXYUnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @DetectorPixelDimsXYUnitsDropDownValueChanged, true);
            app.DetectorPixelDimsXYUnitsDropDown.Value = 'mm';
            app.xyEditField_4Label = uilabel(app.DetectorPanel);
            app.xyEditField_4Label.Text = 'x/y:';
            app.DetectorPixelDimsXYEditField = uieditfield(app.DetectorPanel, 'numeric');
            app.DetectorPixelDimsXYEditField.Limits = [0 Inf];
            app.DetectorPixelDimsXYEditField.ValueDisplayFormat = '%11.10g';
            app.DetectorPixelDimsXYEditField.Value = 0.025;
            app.zEditField_6Label = uilabel(app.DetectorPanel);
            app.zEditField_6Label.Text = 'z:';
            app.DetectorPixelDimsZEditField = uieditfield(app.DetectorPanel, 'numeric');
            app.DetectorPixelDimsZEditField.Limits = [0 Inf];
            app.DetectorPixelDimsZEditField.ValueDisplayFormat = '%11.10g';
            app.DetectorPixelDimsZEditField.Value = 0.025;
            app.DetectorPixelDimsXYEqualZButton = uibutton(app.DetectorPanel, 'push');
            app.DetectorPixelDimsXYEqualZButton.ButtonPushedFcn = createCallbackFcn(app, @DetectorPixelDimsXYEqualZButtonPushed, true);
            app.DetectorPixelDimsXYEqualZButton.Text = '=z';
            app.DetectorPixelDimsZEqualXYButton = uibutton(app.DetectorPanel, 'push');
            app.DetectorPixelDimsZEqualXYButton.ButtonPushedFcn = createCallbackFcn(app, @DetectorPixelDimsZEqualXYButtonPushed, true);
            app.DetectorPixelDimsZEqualXYButton.Text = '=x/y';
            app.DetectorMovesWithPerAngleStepsCheckBox = uicheckbox(app.DetectorPanel);
            app.DetectorMovesWithPerAngleStepsCheckBox.ValueChangedFcn = createCallbackFcn(app, @DetectorMovesWithPerAngleStepsCheckBoxValueChanged, true);
            app.DetectorMovesWithPerAngleStepsCheckBox.Text = 'Moves with Per Angle Steps';
            app.DetectorMovesWithPerAngleStepsCheckBox.Value = true;
            app.DetectorLocationOppositeOfSourceButton = uibutton(app.DetectorPanel, 'push');
            app.DetectorLocationOppositeOfSourceButton.ButtonPushedFcn = createCallbackFcn(app, @DetectorLocationOppositeOfSourceButtonPushed, true);
            app.DetectorLocationOppositeOfSourceButton.Text = 'Opposite of Source';
            app.RadiusforAngularDetectormLabel = uilabel(app.DetectorPanel);
            app.RadiusforAngularDetectormLabel.Text = 'Radius for Angular Detector [m]:';
            app.DetectorAngularDetectorRadiusEditField = uieditfield(app.DetectorPanel, 'numeric');
            app.DetectorAngularDetectorRadiusEditField.Limits = [0 Inf];
            app.DetectorAngularDetectorRadiusEditField.ValueDisplayFormat = '%11.10g';
            app.DetectorAngularDetectorRadiusEditField.Value = 0.025;
            app.SetRadiusToIsocentreDistanceButton = uibutton(app.DetectorPanel, 'push');
            app.SetRadiusToIsocentreDistanceButton.ButtonPushedFcn = createCallbackFcn(app, @SetRadiusToIsocentreDistanceButtonPushed, true);
            app.SetRadiusToIsocentreDistanceButton.Text = 'To Isocentre';
            app.SetRadiusToSourceDistanceButton = uibutton(app.DetectorPanel, 'push');
            app.SetRadiusToSourceDistanceButton.ButtonPushedFcn = createCallbackFcn(app, @SetRadiusToSourceDistanceButtonPushed, true);
            app.SetRadiusToSourceDistanceButton.Text = 'To Source';
            app.DetectorMovesWithScanAngleCheckBox = uicheckbox(app.DetectorPanel);
            app.DetectorMovesWithScanAngleCheckBox.ValueChangedFcn = createCallbackFcn(app, @DetectorMovesWithScanAngleCheckBoxValueChanged, true);
            app.DetectorMovesWithScanAngleCheckBox.Text = 'Moves with Scan Angle';
            app.DetectorMovesWithScanAngleCheckBox.Value = true;
            app.ScanPanel = uipanel(app.ScanSimulationTab);
            app.ScanPanel.Title = 'Scan:';
            app.ScanFilePathLabel = uilabel(app.ScanPanel);
            app.ScanFilePathLabel.Text = 'Scan File Path';
            app.ScanSaveButton = uibutton(app.ScanPanel, 'push');
            app.ScanSaveButton.ButtonPushedFcn = createCallbackFcn(app, @ScanSaveButtonPushed, true);
            app.ScanSaveButton.Text = 'Save';
            app.ScanLoadButton = uibutton(app.ScanPanel, 'push');
            app.ScanLoadButton.ButtonPushedFcn = createCallbackFcn(app, @ScanLoadButtonPushed, true);
            app.ScanLoadButton.Text = 'Load';
            app.ScanSaveInSeparateFileCheckBox = uicheckbox(app.ScanPanel);
            app.ScanSaveInSeparateFileCheckBox.Text = 'Save in Separate File';
            app.ScanSaveInSeparateFileCheckBox.Value = true;
            app.ScanAnglesabcorstartintervalendLabel = uilabel(app.ScanPanel);
            app.ScanAnglesabcorstartintervalendLabel.Text = 'Scan Angles [°] (a,b,c,... or start:interval:end):';
            app.ScanAnglesEditField = uieditfield(app.ScanPanel, 'text');
            app.ScanAnglesEditField.Value = '0:2:358';
            app.SlicePositionsmmabcorstartintervalendLabel = uilabel(app.ScanPanel);
            app.SlicePositionsmmabcorstartintervalendLabel.Text = 'Slice Positions [mm] (a,b,c,... or start:interval:end):';
            app.ScanSlicePositionsEditField = uieditfield(app.ScanPanel, 'text');
            app.ScanSlicePositionsEditField.Value = '-25,-10,0,10,25';
            app.PerAngleTranslationStepsLabel = uilabel(app.ScanPanel);
            app.PerAngleTranslationStepsLabel.Text = 'Per Angle Translation Steps [#]:';
            app.xyEditField_5Label = uilabel(app.ScanPanel);
            app.xyEditField_5Label.Text = 'x/y:';
            app.ScanPerAngleTranslationXYEditField = uieditfield(app.ScanPanel, 'numeric');
            app.ScanPerAngleTranslationXYEditField.Limits = [0 Inf];
            app.ScanPerAngleTranslationXYEditField.ValueDisplayFormat = '%.0f';
            app.ScanPerAngleTranslationXYEditField.Value = 1;
            app.zEditField_7Label = uilabel(app.ScanPanel);
            app.zEditField_7Label.Text = 'z:';
            app.ScanPerAngleTranslationZEditField = uieditfield(app.ScanPanel, 'numeric');
            app.ScanPerAngleTranslationZEditField.Limits = [0 Inf];
            app.ScanPerAngleTranslationZEditField.ValueDisplayFormat = '%.0f';
            app.ScanPerAngleTranslationZEditField.Value = 1;
            app.PerAngleStepDimensionsmmLabel = uilabel(app.ScanPanel);
            app.PerAngleStepDimensionsmmLabel.Text = 'Per Angle Step Dimensions [mm]:';
            app.xyEditField_6Label = uilabel(app.ScanPanel);
            app.xyEditField_6Label.Text = 'x/y:';
            app.ScanPerAngleStepDimsXYEditField = uieditfield(app.ScanPanel, 'numeric');
            app.ScanPerAngleStepDimsXYEditField.Limits = [0 Inf];
            app.ScanPerAngleStepDimsXYEditField.ValueDisplayFormat = '%11.10g';
            app.ScanPerAngleStepDimsXYEditField.Value = 1;
            app.zEditField_8Label = uilabel(app.ScanPanel);
            app.zEditField_8Label.Text = 'z:';
            app.ScanPerAngleStepDimsZEditField = uieditfield(app.ScanPanel, 'numeric');
            app.ScanPerAngleStepDimsZEditField.Limits = [0 Inf];
            app.ScanPerAngleStepDimsZEditField.ValueDisplayFormat = '%11.10g';
            app.ScanPerAngleStepDimsZEditField.Value = 1;
            app.BeamCharacterizationPanel = uipanel(app.ScanPanel);
            app.BeamCharacterizationPanel.Title = 'Beam Characterization:';
            app.BeamCharacterizationFilePathLabel = uilabel(app.BeamCharacterizationPanel);
            app.BeamCharacterizationFilePathLabel.Text = 'Beam Characterization File Path';
            app.BeamCharacterizationSaveInSeparateFileCheckBox = uicheckbox(app.BeamCharacterizationPanel);
            app.BeamCharacterizationSaveInSeparateFileCheckBox.Text = 'Save in Separate File';
            app.BeamCharacterizationSaveInSeparateFileCheckBox.Value = true;
            app.BeamCharacterizationNewButton = uibutton(app.BeamCharacterizationPanel, 'push');
            app.BeamCharacterizationNewButton.ButtonPushedFcn = createCallbackFcn(app, @BeamCharacterizationNewButtonPushed, true);
            app.BeamCharacterizationNewButton.Text = 'New';
            app.BeamCharacterizationLoadButton = uibutton(app.BeamCharacterizationPanel, 'push');
            app.BeamCharacterizationLoadButton.ButtonPushedFcn = createCallbackFcn(app, @BeamCharacterizationLoadButtonPushed, true);
            app.BeamCharacterizationLoadButton.Text = 'Load';
            app.ScanSimulationControlPanel = uipanel(app.ScanSimulationTab);
            app.ScanSimulationControlPanel.Title = 'Control Panel:';
            app.ScanSimMockUpSimulationButton = uibutton(app.ScanSimulationControlPanel, 'push');
            app.ScanSimMockUpSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @ScanSimMockUpSimulationButtonPushed, true);
            app.ScanSimMockUpSimulationButton.Text = 'Mock-Up Simulation';
            app.ScanSimRunSimulationButton = uibutton(app.ScanSimulationControlPanel, 'push');
            app.ScanSimRunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @ScanSimRunSimulationButtonPushed, true);
            app.ScanSimRunSimulationButton.Text = 'Run Simulation';
            app.ScanSimAbortSimulationButton = uibutton(app.ScanSimulationControlPanel, 'push');
            app.ScanSimAbortSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @ScanSimAbortSimulationButtonPushed, true);
            app.ScanSimAbortSimulationButton.Enable = 'off';
            app.ScanSimAbortSimulationButton.Text = 'Abort Simulation';
            app.SimulationRunPanel = uipanel(app.ScanSimulationTab);
            app.SimulationRunPanel.Title = 'Simulation Run:';
            app.SimulationRunSavePathLabel = uilabel(app.SimulationRunPanel);
            app.SimulationRunSavePathLabel.Text = 'Simulation Run Save Path';
            app.SimulationRunSavePathChangeButton = uibutton(app.SimulationRunPanel, 'push');
            app.SimulationRunSavePathChangeButton.ButtonPushedFcn = createCallbackFcn(app, @SimulationRunSavePathChangeButtonPushed, true);
            app.SimulationRunSavePathChangeButton.Text = 'Change';
            app.CPUsEditFieldLabel = uilabel(app.SimulationRunPanel);
            app.CPUsEditFieldLabel.Text = '# CPUs:';
            app.SimulationRunNumCPUsEditField = uieditfield(app.SimulationRunPanel, 'numeric');
            app.SimulationRunNumCPUsEditField.Limits = [1 Inf];
            app.SimulationRunNumCPUsEditField.ValueDisplayFormat = '%.0f';
            app.SimulationRunNumCPUsEditField.Value = 1;
            app.SimulationRunNumCPUsAvailableLabel = uilabel(app.SimulationRunPanel);
            app.SimulationRunNumCPUsAvailableLabel.Text = '/24';
            app.SimulationRunAnimationListBoxLabel = uilabel(app.SimulationRunPanel);
            app.SimulationRunAnimationListBoxLabel.Text = 'Simulation Run Animation:';
            app.SimulationRunShowDetectorRayTracesCheckBox = uicheckbox(app.SimulationRunPanel);
            app.SimulationRunShowDetectorRayTracesCheckBox.Text = 'Detector Ray Traces';
            app.SimulationRunShowDetectorValuesCheckBox = uicheckbox(app.SimulationRunPanel);
            app.SimulationRunShowDetectorValuesCheckBox.Text = 'Detector Values';
            app.SimulationRunShowAnglesCheckBox = uicheckbox(app.SimulationRunPanel);
            app.SimulationRunShowAnglesCheckBox.ValueChangedFcn = createCallbackFcn(app, @SimulationRunShowAnglesCheckBoxValueChanged, true);
            app.SimulationRunShowAnglesCheckBox.Text = '  Angles';
            app.SimulationRunShowSlicesCheckBox = uicheckbox(app.SimulationRunPanel);
            app.SimulationRunShowSlicesCheckBox.ValueChangedFcn = createCallbackFcn(app, @SimulationRunShowSlicesCheckBoxValueChanged, true);
            app.SimulationRunShowSlicesCheckBox.Text = 'Slices';
            app.SimulationRunShowDetectorRasterCheckBox = uicheckbox(app.SimulationRunPanel);
            app.SimulationRunShowDetectorRasterCheckBox.ValueChangedFcn = createCallbackFcn(app, @SimulationRunShowDetectorRasterCheckBoxValueChanged, true);
            app.SimulationRunShowDetectorRasterCheckBox.Text = '      Detector Raster';
            app.SimulationRunShowPerAngleRasterCheckBox = uicheckbox(app.SimulationRunPanel);
            app.SimulationRunShowPerAngleRasterCheckBox.ValueChangedFcn = createCallbackFcn(app, @SimulationRunShowPerAngleRasterCheckBoxValueChanged, true);
            app.SimulationRunShowPerAngleRasterCheckBox.Text = '    Per Angle Raster';
            app.SimulationRunRunPerformanceButtonGroup = uibuttongroup(app.SimulationRunPanel);
            app.SimulationRunRunPerformanceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SimulationRunRunPerformanceButtonGroupSelectionChanged, true);
            app.SimulationRunRunPerformanceButtonGroup.Title = 'Run Performance';
            app.SimulationRunLowPerformanceButton = uiradiobutton(app.SimulationRunRunPerformanceButtonGroup);
            app.SimulationRunLowPerformanceButton.Text = 'Low Performance (Animation)';
            app.SimulationRunHighPerformanceButton = uiradiobutton(app.SimulationRunRunPerformanceButtonGroup);
            app.SimulationRunHighPerformanceButton.Text = 'High Performance';
            app.SimulationRunHighPerformanceButton.Value = true;
            app.SimulationRunHighWithMultipleCPUsButton = uiradiobutton(app.SimulationRunRunPerformanceButtonGroup);
            app.SimulationRunHighWithMultipleCPUsButton.Text = 'High with Multiple CPUs';
            app.SimulationRunHighWithGPUButton = uiradiobutton(app.SimulationRunRunPerformanceButtonGroup);
            app.SimulationRunHighWithGPUButton.Text = 'High with GPU (TIGRE)';
            app.NotesTextAreaLabel = uilabel(app.SimulationRunPanel);
            app.NotesTextAreaLabel.Text = 'Notes:';
            app.SimulationRunNotesTextArea = uitextarea(app.SimulationRunPanel);
            app.SimulationRunNotesTextArea.Value = {'Notes notes notes'; 'More notes notes notes'; ''};
            app.SimulationRunUseMEXCodeCheckBox = uicheckbox(app.SimulationRunPanel);
            app.SimulationRunUseMEXCodeCheckBox.Text = 'Use MEX Code';
            app.DatasetReconstructionTab = uitab(app.TabGroup);
            app.DatasetReconstructionTab.Title = 'Dataset Reconstruction';
            app.DataSetReconstructionControlPanel = uipanel(app.DatasetReconstructionTab);
            app.DataSetReconstructionControlPanel.Title = 'Control Panel:';
            app.DataSetReconRunReconstructionButton = uibutton(app.DataSetReconstructionControlPanel, 'push');
            app.DataSetReconRunReconstructionButton.ButtonPushedFcn = createCallbackFcn(app, @DataSetReconRunReconstructionButtonPushed, true);
            app.DataSetReconRunReconstructionButton.Text = 'Run Reconstructions';
            app.DataSetReconAbortReconstructionButton = uibutton(app.DataSetReconstructionControlPanel, 'push');
            app.DataSetReconAbortReconstructionButton.ButtonPushedFcn = createCallbackFcn(app, @DataSetReconAbortReconstructionButtonPushed, true);
            app.DataSetReconAbortReconstructionButton.Enable = 'off';
            app.DataSetReconAbortReconstructionButton.Text = 'Abort';
            app.DataSetReconAddToQueueButton = uibutton(app.DataSetReconstructionControlPanel, 'push');
            app.DataSetReconAddToQueueButton.ButtonPushedFcn = createCallbackFcn(app, @DataSetReconAddToQueueButtonPushed, true);
            app.DataSetReconAddToQueueButton.Text = 'Add To Queue';
            app.DataSetReconEmptyQueueButton = uibutton(app.DataSetReconstructionControlPanel, 'push');
            app.DataSetReconEmptyQueueButton.ButtonPushedFcn = createCallbackFcn(app, @DataSetReconEmptyQueueButtonPushed, true);
            app.DataSetReconEmptyQueueButton.Text = 'Empty Queue';
            app.DataSetReconViewQueueButton = uibutton(app.DataSetReconstructionControlPanel, 'push');
            app.DataSetReconViewQueueButton.ButtonPushedFcn = createCallbackFcn(app, @DataSetReconViewQueueButtonPushed, true);
            app.DataSetReconViewQueueButton.Text = 'View Queue';
            app.SimulationImagingRunInformationPanel = uipanel(app.DatasetReconstructionTab);
            app.SimulationImagingRunInformationPanel.Title = 'Simulation/Imaging Run Information:';
            app.SimulationRunInfoLoadPathLabel = uilabel(app.SimulationImagingRunInformationPanel);
            app.SimulationRunInfoLoadPathLabel.Text = 'Simulation Run Load Path';
            app.StartDateTimeEditFieldLabel = uilabel(app.SimulationImagingRunInformationPanel);
            app.StartDateTimeEditFieldLabel.Text = 'Start Date/Time:';
            app.SimulationRunInfoStartDateTimeEditField = uieditfield(app.SimulationImagingRunInformationPanel, 'text');
            app.SimulationRunInfoStartDateTimeEditField.Editable = 'off';
            app.SimulationRunInfoStartDateTimeEditField.Value = 'Jan 15, 2023   24:25:64';
            app.RunTimeEditFieldLabel = uilabel(app.SimulationImagingRunInformationPanel);
            app.RunTimeEditFieldLabel.Text = 'Run Time:';
            app.SimulationRunInfoRunTimeEditField = uieditfield(app.SimulationImagingRunInformationPanel, 'text');
            app.SimulationRunInfoRunTimeEditField.Editable = 'off';
            app.SimulationRunInfoRunTimeEditField.Value = '4324:25:64';
            app.RunPerformanceLabel = uilabel(app.SimulationImagingRunInformationPanel);
            app.RunPerformanceLabel.Text = 'Run Performance:';
            app.SimulationRunInfoRunPerformanceEditField = uieditfield(app.SimulationImagingRunInformationPanel, 'text');
            app.SimulationRunInfoRunPerformanceEditField.Editable = 'off';
            app.SimulationRunInfoRunPerformanceEditField.Value = 'High with Multiple CPUs';
            app.ComputerArchitectureScannerSummaryLabel = uilabel(app.SimulationImagingRunInformationPanel);
            app.ComputerArchitectureScannerSummaryLabel.Text = 'Computer Architecture/Scanner Summary:';
            app.SimulationRunInfoComputerArchitectureSummaryTextArea = uitextarea(app.SimulationImagingRunInformationPanel);
            app.SimulationRunInfoComputerArchitectureSummaryTextArea.Editable = 'off';
            app.SimulationRunInfoComputerArchitectureSummaryTextArea.Value = {'Computer Information:'; 'System Name: Bravo'; 'CPU Clock Speed: 34GHz'; 'CPU Cache Size: 54Mb'; '# CPUS Cores: 8'; 'OS: Windows 7 (v3.4.5)'; 'User Memory: 4.54Gb'; 'System Memory: 6.78Gb'; ''; 'GPU Information:'; 'No GPU found'};
            app.NotesTextArea_3Label = uilabel(app.SimulationImagingRunInformationPanel);
            app.NotesTextArea_3Label.Text = 'Notes:';
            app.SimulationRunInfoNotesTextArea = uitextarea(app.SimulationImagingRunInformationPanel);
            app.SimulationRunInfoNotesTextArea.Editable = 'off';
            app.SimulationRunInfoNotesTextArea.Value = {'Notes notes'; 'ntoes'; 'notes'; 'notes'; 'notes'; ''};
            app.InterpretedScanGeometryTextAreaLabel = uilabel(app.SimulationImagingRunInformationPanel);
            app.InterpretedScanGeometryTextAreaLabel.Text = 'Interpreted Scan Geometry:';
            app.SimulationRunInfoInterpretedScanGeometryTextArea = uitextarea(app.SimulationImagingRunInformationPanel);
            app.SimulationRunInfoInterpretedScanGeometryTextArea.Editable = 'off';
            app.SimulationRunInfoInterpretedScanGeometryTextArea.Value = {'3rd Generation'; 'Fan Beam'; '15 Scan Angles [°] (0:2:358)'; '20 Slices [mm] (-20:5:20)'; 'Detector Size: 10x15'; 'Per Angle Translation Steps: 20x1'};
            app.SimulationRunInfoLoadSimulationButton = uibutton(app.SimulationImagingRunInformationPanel, 'push');
            app.SimulationRunInfoLoadSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @SimulationRunInfoLoadSimulationButtonPushed, true);
            app.SimulationRunInfoLoadSimulationButton.Enable = 'off';
            app.SimulationRunInfoLoadSimulationButton.Text = 'Load';
            app.LoadSimulationImagingScanintoGyrfalconLabel = uilabel(app.SimulationImagingRunInformationPanel);
            app.LoadSimulationImagingScanintoGyrfalconLabel.Text = 'Load Simulation/Imaging Scan into Gyrfalcon:';
            app.ScanSimulationtabdatawillbeoverwrittenLabel = uilabel(app.SimulationImagingRunInformationPanel);
            app.ScanSimulationtabdatawillbeoverwrittenLabel.Text = '("Scan Simulation" tab data will be overwritten!)';
            app.VersionLabel = uilabel(app.SimulationImagingRunInformationPanel);
            app.VersionLabel.Text = 'Version:';
            app.SimulationRunInfoGyrfalconVersionEditField = uieditfield(app.SimulationImagingRunInformationPanel, 'text');
            app.SimulationRunInfoGyrfalconVersionEditField.Editable = 'off';
            app.SimulationRunInfoGyrfalconVersionEditField.Value = 'v1.0.1';
            app.DataSetReconLoadDataButton = uibutton(app.SimulationImagingRunInformationPanel, 'push');
            app.DataSetReconLoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @DataSetReconLoadDataButtonPushed, true);
            app.DataSetReconLoadDataButton.Text = 'Load Data';
            app.ReconstructionRunSettingsPanel = uipanel(app.DatasetReconstructionTab);
            app.ReconstructionRunSettingsPanel.Title = 'Reconstruction Run Settings:';
            app.ReconstructionRunSavePathChangeButton = uibutton(app.ReconstructionRunSettingsPanel, 'push');
            app.ReconstructionRunSavePathChangeButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstructionRunSavePathChangeButtonPushed, true);
            app.ReconstructionRunSavePathChangeButton.Text = 'Change';
            app.ReconstructedSliceDimensionsLabel = uilabel(app.ReconstructionRunSettingsPanel);
            app.ReconstructedSliceDimensionsLabel.Text = 'Reconstructed Slice Dimensions [#]:';
            app.ReconstructedSlicePixelDimensionsmmLabel = uilabel(app.ReconstructionRunSettingsPanel);
            app.ReconstructedSlicePixelDimensionsmmLabel.Text = 'Reconstructed Slice Pixel Dimensions [mm]:';
            app.xEditField_5Label = uilabel(app.ReconstructionRunSettingsPanel);
            app.xEditField_5Label.Text = 'x:';
            app.ReconstructionRunSliceDimsXEditField = uieditfield(app.ReconstructionRunSettingsPanel, 'numeric');
            app.ReconstructionRunSliceDimsXEditField.Limits = [0 Inf];
            app.ReconstructionRunSliceDimsXEditField.ValueDisplayFormat = '%.0f';
            app.ReconstructionRunSliceDimsXEditField.Value = 1;
            app.yEditField_5Label = uilabel(app.ReconstructionRunSettingsPanel);
            app.yEditField_5Label.Text = 'y:';
            app.ReconstructionRunSliceDimsYEditField = uieditfield(app.ReconstructionRunSettingsPanel, 'numeric');
            app.ReconstructionRunSliceDimsYEditField.Limits = [0 Inf];
            app.ReconstructionRunSliceDimsYEditField.ValueDisplayFormat = '%.0f';
            app.ReconstructionRunSliceDimsYEditField.Value = 1;
            app.xEditField_6Label = uilabel(app.ReconstructionRunSettingsPanel);
            app.xEditField_6Label.Text = 'x:';
            app.ReconstructionRunSlicePixelDimsXEditField = uieditfield(app.ReconstructionRunSettingsPanel, 'numeric');
            app.ReconstructionRunSlicePixelDimsXEditField.Limits = [0 Inf];
            app.ReconstructionRunSlicePixelDimsXEditField.ValueDisplayFormat = '%11.10g';
            app.ReconstructionRunSlicePixelDimsXEditField.Value = 0.025;
            app.yEditField_6Label = uilabel(app.ReconstructionRunSettingsPanel);
            app.yEditField_6Label.Text = 'y:';
            app.ReconstructionRunSlicePixelDimsYEditField = uieditfield(app.ReconstructionRunSettingsPanel, 'numeric');
            app.ReconstructionRunSlicePixelDimsYEditField.Limits = [0 Inf];
            app.ReconstructionRunSlicePixelDimsYEditField.ValueDisplayFormat = '%11.10g';
            app.ReconstructionRunSlicePixelDimsYEditField.Value = 0.025;
            app.ReconstructionAlgorithmLabel = uilabel(app.ReconstructionRunSettingsPanel);
            app.ReconstructionAlgorithmLabel.Text = 'Reconstruction Algorithm:';
            app.ReconstructionRunAlgorithmDropDown = uidropdown(app.ReconstructionRunSettingsPanel);
            app.ReconstructionRunAlgorithmDropDown.Items = {'Fiiltered Back Projection', 'PAIR', 'Option 3', 'Option 4'};
            app.ReconstructionRunAlgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @ReconstructionRunAlgorithmDropDownValueChanged, true);
            app.ReconstructionRunAlgorithmDropDown.Value = 'Fiiltered Back Projection';
            app.Label_4 = uilabel(app.ReconstructionRunSettingsPanel);
            app.Label_4.Text = '________________________________________________________________________________________________________________';
            app.ReconstructedDataSetDimensionsLabel = uilabel(app.ReconstructionRunSettingsPanel);
            app.ReconstructedDataSetDimensionsLabel.Text = 'Reconstructed Data Set Dimensions [#]:';
            app.ReconstructedDataSetVoxelDimensionsmmLabel = uilabel(app.ReconstructionRunSettingsPanel);
            app.ReconstructedDataSetVoxelDimensionsmmLabel.Text = 'Reconstructed Data Set Voxel Dimensions [mm]:';
            app.xEditField_7Label = uilabel(app.ReconstructionRunSettingsPanel);
            app.xEditField_7Label.Text = 'x:';
            app.ReconstructionRunDataSetDimsXEditField = uieditfield(app.ReconstructionRunSettingsPanel, 'numeric');
            app.ReconstructionRunDataSetDimsXEditField.Limits = [1 Inf];
            app.ReconstructionRunDataSetDimsXEditField.ValueDisplayFormat = '%.0f';
            app.ReconstructionRunDataSetDimsXEditField.Value = 256;
            app.yEditField_7Label = uilabel(app.ReconstructionRunSettingsPanel);
            app.yEditField_7Label.Text = 'y:';
            app.ReconstructionRunDataSetDimsYEditField = uieditfield(app.ReconstructionRunSettingsPanel, 'numeric');
            app.ReconstructionRunDataSetDimsYEditField.Limits = [1 Inf];
            app.ReconstructionRunDataSetDimsYEditField.ValueDisplayFormat = '%.0f';
            app.ReconstructionRunDataSetDimsYEditField.Value = 256;
            app.zEditField_9Label = uilabel(app.ReconstructionRunSettingsPanel);
            app.zEditField_9Label.Text = 'z:';
            app.ReconstructionRunDataSetDimsZEditField = uieditfield(app.ReconstructionRunSettingsPanel, 'numeric');
            app.ReconstructionRunDataSetDimsZEditField.Limits = [1 Inf];
            app.ReconstructionRunDataSetDimsZEditField.ValueDisplayFormat = '%.0f';
            app.ReconstructionRunDataSetDimsZEditField.Value = 256;
            app.xEditField_8Label = uilabel(app.ReconstructionRunSettingsPanel);
            app.xEditField_8Label.Text = 'x:';
            app.ReconstructionRunDataSetVoxelDimsXEditField = uieditfield(app.ReconstructionRunSettingsPanel, 'numeric');
            app.ReconstructionRunDataSetVoxelDimsXEditField.Limits = [0 Inf];
            app.ReconstructionRunDataSetVoxelDimsXEditField.ValueDisplayFormat = '%11.10g';
            app.ReconstructionRunDataSetVoxelDimsXEditField.Value = 0.025;
            app.yEditField_8Label = uilabel(app.ReconstructionRunSettingsPanel);
            app.yEditField_8Label.Text = 'y:';
            app.ReconstructionRunDataSetVoxelDimsYEditField = uieditfield(app.ReconstructionRunSettingsPanel, 'numeric');
            app.ReconstructionRunDataSetVoxelDimsYEditField.Limits = [0 Inf];
            app.ReconstructionRunDataSetVoxelDimsYEditField.ValueDisplayFormat = '%11.10g';
            app.ReconstructionRunDataSetVoxelDimsYEditField.Value = 0.025;
            app.zEditField_10Label = uilabel(app.ReconstructionRunSettingsPanel);
            app.zEditField_10Label.Text = 'z:';
            app.ReconstructionRunDataSetVoxelDimsZEditField = uieditfield(app.ReconstructionRunSettingsPanel, 'numeric');
            app.ReconstructionRunDataSetVoxelDimsZEditField.Limits = [0 Inf];
            app.ReconstructionRunDataSetVoxelDimsZEditField.ValueDisplayFormat = '%11.10g';
            app.ReconstructionRunDataSetVoxelDimsZEditField.Value = 0.025;
            app.DInterpolationTypeDropDownLabel = uilabel(app.ReconstructionRunSettingsPanel);
            app.DInterpolationTypeDropDownLabel.Text = '3D Interpolation Type:';
            app.ReconstructionRun3DInterpolationTypeDropDown = uidropdown(app.ReconstructionRunSettingsPanel);
            app.ReconstructionRun3DInterpolationTypeDropDown.Items = {'Nearest Neighbour', 'Linear', 'Option 3', 'Option 4'};
            app.ReconstructionRun3DInterpolationTypeDropDown.Value = 'Nearest Neighbour';
            app.Label_5 = uilabel(app.ReconstructionRunSettingsPanel);
            app.Label_5.Text = '__________________________________________________________________________________________________________________';
            app.Label_6 = uilabel(app.ReconstructionRunSettingsPanel);
            app.Label_6.Text = '________________________________________________________________________________________________________________';
            app.slicesintoa3DdatasetLabel = uilabel(app.ReconstructionRunSettingsPanel);
            app.slicesintoa3DdatasetLabel.Text = 'slices into a 3D data set';
            app.UsedforinterpolatingreconstructedLabel = uilabel(app.ReconstructionRunSettingsPanel);
            app.UsedforinterpolatingreconstructedLabel.Text = 'Used for interpolating reconstructed';
            app.ReconstructionRunNumCPUsAvailableLabel = uilabel(app.ReconstructionRunSettingsPanel);
            app.ReconstructionRunNumCPUsAvailableLabel.Text = '/24';
            app.CPUsEditField_2Label = uilabel(app.ReconstructionRunSettingsPanel);
            app.CPUsEditField_2Label.Text = '# CPUs:';
            app.ReconstructionRunNumCPUsEditField = uieditfield(app.ReconstructionRunSettingsPanel, 'numeric');
            app.ReconstructionRunNumCPUsEditField.Limits = [1 Inf];
            app.ReconstructionRunNumCPUsEditField.ValueDisplayFormat = '%.0f';
            app.ReconstructionRunNumCPUsEditField.Value = 1;
            app.ReconstructionAlgorithmSettingsTabGroup = uitabgroup(app.ReconstructionRunSettingsPanel);
            app.Gen1FBP_SettingsTab = uitab(app.ReconstructionAlgorithmSettingsTabGroup);
            app.Gen1FBP_SettingsTab.Title = '1st Generation FBP Setiings';
            app.FilterTypeLabel = uilabel(app.Gen1FBP_SettingsTab);
            app.FilterTypeLabel.Text = 'Filter Type:';
            app.Gen1FBP_FilterTypeDropDown = uidropdown(app.Gen1FBP_SettingsTab);
            app.Gen1FBP_FilterTypeDropDown.Items = {'Shepp-Logan', 'Cosine', 'Option 3', 'Option 4'};
            app.Gen1FBP_FilterTypeDropDown.Value = 'Shepp-Logan';
            app.ForNoiseReductionLabel = uilabel(app.Gen1FBP_SettingsTab);
            app.ForNoiseReductionLabel.Text = '(For Noise Reduction)';
            app.Gen1FBP_ApplyRampRamLakFilterCheckBox = uicheckbox(app.Gen1FBP_SettingsTab);
            app.Gen1FBP_ApplyRampRamLakFilterCheckBox.Text = 'Apply Ramp (Ram-Lak) Filter';
            app.WeightsdatainFourierSpaceLabel = uilabel(app.Gen1FBP_SettingsTab);
            app.WeightsdatainFourierSpaceLabel.Text = '(Weights data in Fourier Space)';
            app.Gen1FBP_ApplyBandlimitingofHighFrequenciesCheckBox = uicheckbox(app.Gen1FBP_SettingsTab);
            app.Gen1FBP_ApplyBandlimitingofHighFrequenciesCheckBox.Text = 'Apply Bandlimiting of High Frequencies';
            app.BasedonDetectorPixelSpacingSizeLabel = uilabel(app.Gen1FBP_SettingsTab);
            app.BasedonDetectorPixelSpacingSizeLabel.Text = '(Based on Detector Pixel Spacing/Size)';
            app.Gen1FBP_SaveSinogramsandReconstructionVideosCheckBox = uicheckbox(app.Gen1FBP_SettingsTab);
            app.Gen1FBP_SaveSinogramsandReconstructionVideosCheckBox.Text = 'Save Sinograms and Reconstruction Videos';
            app.WeightsdatainFourierSpaceLabel_2 = uilabel(app.Gen1FBP_SettingsTab);
            app.WeightsdatainFourierSpaceLabel_2.Text = '(May use a lot of space; write times not included in reconstruction speed)';
            app.ForfindingvaluesbetweendetectorcentresLabel = uilabel(app.Gen1FBP_SettingsTab);
            app.ForfindingvaluesbetweendetectorcentresLabel.Text = '(For finding values between detector centres)';
            app.InterpolationTypeDropDownLabel = uilabel(app.Gen1FBP_SettingsTab);
            app.InterpolationTypeDropDownLabel.Text = 'Interpolation Type:';
            app.Gen1FBP_InterpolationTypeDropDown = uidropdown(app.Gen1FBP_SettingsTab);
            app.Gen1FBP_InterpolationTypeDropDown.Items = {'Nearest Neighbour', 'Linear', 'Option 3', 'Option 4'};
            app.Gen1FBP_InterpolationTypeDropDown.Value = 'Nearest Neighbour';
            app.Gen1PAIR_SettingsTab = uitab(app.ReconstructionAlgorithmSettingsTabGroup);
            app.Gen1PAIR_SettingsTab.Title = '1st Generation PAIR Settings';
            app.Gen1PAIR_UsePreComputedAlphaMatrixCheckBox = uicheckbox(app.Gen1PAIR_SettingsTab);
            app.Gen1PAIR_UsePreComputedAlphaMatrixCheckBox.Enable = 'off';
            app.Gen1PAIR_UsePreComputedAlphaMatrixCheckBox.Text = 'Use Pre-Computed Alpha Matrix';
            app.Gen1PAIR_UseNonPartialVoxelWeightingCheckBox = uicheckbox(app.Gen1PAIR_SettingsTab);
            app.Gen1PAIR_UseNonPartialVoxelWeightingCheckBox.Text = 'Use Non-Partial Voxel Weighting';
            app.NonPartialVoxelWeightingCutoff01Label = uilabel(app.Gen1PAIR_SettingsTab);
            app.NonPartialVoxelWeightingCutoff01Label.Text = 'Non-Partial Voxel Weighting Cut-off [0-1]:';
            app.Gen1PAIR_NonPartialVoxelWeightingCutoffEditField = uieditfield(app.Gen1PAIR_SettingsTab, 'numeric');
            app.Gen1PAIR_NonPartialVoxelWeightingCutoffEditField.Limits = [0 1];
            app.Label_8 = uilabel(app.Gen1PAIR_SettingsTab);
            app.Label_8.Text = 'Normalized length through a voxel must be greater than';
            app.Label_9 = uilabel(app.Gen1PAIR_SettingsTab);
            app.Label_9.Text = 'cut-off to register as passing through the voxel';
            app.ConeBeamFDK_SettingsTab = uitab(app.ReconstructionAlgorithmSettingsTabGroup);
            app.ConeBeamFDK_SettingsTab.Title = 'Cone Beam FDK Settings';
            app.CBCT_FDK_ParkerCheckBox = uicheckbox(app.ConeBeamFDK_SettingsTab);
            app.CBCT_FDK_ParkerCheckBox.Text = 'Use Parker';
            app.FilterTypeLabel_2 = uilabel(app.ConeBeamFDK_SettingsTab);
            app.FilterTypeLabel_2.Text = 'Filter Type:';
            app.CBCT_FDK_FilterDropDown = uidropdown(app.ConeBeamFDK_SettingsTab);
            app.CBCT_FDK_FilterDropDown.Items = {'Ram-Lak', 'Cosine', 'Option 3', 'Option 4'};
            app.CBCT_FDK_FilterDropDown.Value = 'Ram-Lak';
            app.ConeBeamPAIR_SettingsTab = uitab(app.ReconstructionAlgorithmSettingsTabGroup);
            app.ConeBeamPAIR_SettingsTab.Title = 'Cone Beam PAIR Settings';
            app.RayTraceMatricesLabel = uilabel(app.ConeBeamPAIR_SettingsTab);
            app.RayTraceMatricesLabel.Text = 'Ray Trace Matrices:';
            app.CBCT_PAIR_RayTraceMatricesSavePathEditField = uieditfield(app.ConeBeamPAIR_SettingsTab, 'text');
            app.CBCT_PAIR_RayTraceMatricesSavePathEditField.Editable = 'off';
            app.CBCT_PAIR_RayTraceMatricesSavePathEditField.Value = 'Simulation Save Path Setting';
            app.CBCT_PAIR_RayTraceMatricesLoadFromButton = uibutton(app.ConeBeamPAIR_SettingsTab, 'push');
            app.CBCT_PAIR_RayTraceMatricesLoadFromButton.ButtonPushedFcn = createCallbackFcn(app, @CBCT_PAIR_RayTraceMatricesLoadFromButtonPushed, true);
            app.CBCT_PAIR_RayTraceMatricesLoadFromButton.Text = 'Load From';
            app.CBCT_PAIR_RayTraceMatricesSaveToButton = uibutton(app.ConeBeamPAIR_SettingsTab, 'push');
            app.CBCT_PAIR_RayTraceMatricesSaveToButton.ButtonPushedFcn = createCallbackFcn(app, @CBCT_PAIR_RayTraceMatricesSaveToButtonPushed, true);
            app.CBCT_PAIR_RayTraceMatricesSaveToButton.Text = 'Save To';
            app.AlphaandSelectionMatricesLabel = uilabel(app.ConeBeamPAIR_SettingsTab);
            app.AlphaandSelectionMatricesLabel.Text = 'Alpha and Selection Matrices:';
            app.CBCT_PAIR_AlphaMatricesSavePathEditField = uieditfield(app.ConeBeamPAIR_SettingsTab, 'text');
            app.CBCT_PAIR_AlphaMatricesSavePathEditField.Editable = 'off';
            app.CBCT_PAIR_AlphaMatricesSavePathEditField.Value = 'Simulation Save Path Setting';
            app.CBCT_PAIR_AlphaMatricesLoadFromButton = uibutton(app.ConeBeamPAIR_SettingsTab, 'push');
            app.CBCT_PAIR_AlphaMatricesLoadFromButton.ButtonPushedFcn = createCallbackFcn(app, @CBCT_PAIR_AlphaMatricesLoadFromButtonPushed, true);
            app.CBCT_PAIR_AlphaMatricesLoadFromButton.Text = 'Load From';
            app.CBCT_PAIR_AlphaMatricesSaveToButton = uibutton(app.ConeBeamPAIR_SettingsTab, 'push');
            app.CBCT_PAIR_AlphaMatricesSaveToButton.ButtonPushedFcn = createCallbackFcn(app, @CBCT_PAIR_AlphaMatricesSaveToButtonPushed, true);
            app.CBCT_PAIR_AlphaMatricesSaveToButton.Text = 'Save To';
            app.NumberofPartitionsinFinalIterationLabel = uilabel(app.ConeBeamPAIR_SettingsTab);
            app.NumberofPartitionsinFinalIterationLabel.Text = 'Number of Partitions in Final Iteration:';
            app.CBCT_PAIR_NumberPartitionsEditField = uieditfield(app.ConeBeamPAIR_SettingsTab, 'numeric');
            app.CBCT_PAIR_NumberPartitionsEditField.Limits = [1 Inf];
            app.CBCT_PAIR_NumberPartitionsEditField.ValueDisplayFormat = '%.0f';
            app.CBCT_PAIR_NumberPartitionsEditField.Value = 1;
            app.UsediflargeAlphamatricescannotfitinmemoryLabel = uilabel(app.ConeBeamPAIR_SettingsTab);
            app.UsediflargeAlphamatricescannotfitinmemoryLabel.Text = 'Used if the Alpha matrix cannot fit in memory. Should be a power of 2 for multiple iterations.';
            app.Numberof2xStepUpIterationsLabel = uilabel(app.ConeBeamPAIR_SettingsTab);
            app.Numberof2xStepUpIterationsLabel.Text = 'Number of 2x Step-Up Iterations:';
            app.CBCT_PAIR_NumberIterationsEditField = uieditfield(app.ConeBeamPAIR_SettingsTab, 'numeric');
            app.CBCT_PAIR_NumberIterationsEditField.Limits = [1 Inf];
            app.CBCT_PAIR_NumberIterationsEditField.ValueDisplayFormat = '%.0f';
            app.CBCT_PAIR_NumberIterationsEditField.Value = 1;
            app.UsediflargeAlphamatricescannotfitinmemoryLabel_2 = uilabel(app.ConeBeamPAIR_SettingsTab);
            app.UsediflargeAlphamatricescannotfitinmemoryLabel_2.Text = 'If multiple partitions are needed, the reconstruction can be done by starting with lower';
            app.NumberofAveragesLabel = uilabel(app.ConeBeamPAIR_SettingsTab);
            app.NumberofAveragesLabel.Text = 'Number of Averages:';
            app.CBCT_PAIR_NumberAveragesEditField = uieditfield(app.ConeBeamPAIR_SettingsTab, 'numeric');
            app.CBCT_PAIR_NumberAveragesEditField.Limits = [1 Inf];
            app.CBCT_PAIR_NumberAveragesEditField.ValueDisplayFormat = '%.0f';
            app.CBCT_PAIR_NumberAveragesEditField.Value = 1;
            app.UsediflargeAlphamatricescannotfitinmemoryLabel_3 = uilabel(app.ConeBeamPAIR_SettingsTab);
            app.UsediflargeAlphamatricescannotfitinmemoryLabel_3.Text = 'For iterations without a single partition, multiple partitionings can be used and averaged.';
            app.NumberofRaysinMatrixComputeBatchLabel = uilabel(app.ConeBeamPAIR_SettingsTab);
            app.NumberofRaysinMatrixComputeBatchLabel.Text = 'Number of Rays in Matrix Compute Batch:';
            app.CBCT_PAIR_NumberRaysInBatchEditField = uieditfield(app.ConeBeamPAIR_SettingsTab, 'numeric');
            app.CBCT_PAIR_NumberRaysInBatchEditField.Limits = [1 Inf];
            app.CBCT_PAIR_NumberRaysInBatchEditField.ValueDisplayFormat = '%.0f';
            app.CBCT_PAIR_NumberRaysInBatchEditField.Value = 1;
            app.UsediflargeAlphamatricescannotfitinmemoryLabel_4 = uilabel(app.ConeBeamPAIR_SettingsTab);
            app.UsediflargeAlphamatricescannotfitinmemoryLabel_4.Text = 'resolutions, giving an initial solution for the final iteration to work with. Each Step-Up';
            app.UsediflargeAlphamatricescannotfitinmemoryLabel_5 = uilabel(app.ConeBeamPAIR_SettingsTab);
            app.UsediflargeAlphamatricescannotfitinmemoryLabel_5.Text = 'iteration will have voxels that are 2x larger, giving half the number of voxels. Ideally,';
            app.UsediflargeAlphamatricescannotfitinmemoryLabel_6 = uilabel(app.ConeBeamPAIR_SettingsTab);
            app.UsediflargeAlphamatricescannotfitinmemoryLabel_6.Text = 'the first iteration should have a single partition of voxels.';
            app.ConeBeamOSSARTSettingsTab = uitab(app.ReconstructionAlgorithmSettingsTabGroup);
            app.ConeBeamOSSARTSettingsTab.Title = 'Cone Beam OS-SART Settings';
            app.InitialImageLabel = uilabel(app.ConeBeamOSSARTSettingsTab);
            app.InitialImageLabel.Text = 'Initial Image:';
            app.CBCT_OSSART_InitialImageDropDown = uidropdown(app.ConeBeamOSSARTSettingsTab);
            app.CBCT_OSSART_InitialImageDropDown.Items = {'None', 'Cosine', 'Option 3', 'Option 4'};
            app.CBCT_OSSART_InitialImageDropDown.Value = 'None';
            app.ForwardProjectionAccuracyLabel = uilabel(app.ConeBeamOSSARTSettingsTab);
            app.ForwardProjectionAccuracyLabel.Text = 'Forward Projection Accuracy:';
            app.CBCT_OSSART_ForwardProjectionAccuracyEditField = uieditfield(app.ConeBeamOSSARTSettingsTab, 'numeric');
            app.CBCT_OSSART_ForwardProjectionAccuracyEditField.Limits = [0 1];
            app.CBCT_OSSART_ForwardProjectionAccuracyEditField.Value = 1;
            app.NumberofIterationsLabel = uilabel(app.ConeBeamOSSARTSettingsTab);
            app.NumberofIterationsLabel.Text = 'Number of Iterations:';
            app.CBCT_OSSART_NumberOfIterationsEditField = uieditfield(app.ConeBeamOSSARTSettingsTab, 'numeric');
            app.CBCT_OSSART_NumberOfIterationsEditField.Limits = [1 Inf];
            app.CBCT_OSSART_NumberOfIterationsEditField.ValueDisplayFormat = '%.0f';
            app.CBCT_OSSART_NumberOfIterationsEditField.Value = 1;
            app.BlockSizeLabel = uilabel(app.ConeBeamOSSARTSettingsTab);
            app.BlockSizeLabel.Text = 'Block Size:';
            app.CBCT_OSSART_BlockSizeEditField = uieditfield(app.ConeBeamOSSARTSettingsTab, 'numeric');
            app.CBCT_OSSART_BlockSizeEditField.Limits = [1 Inf];
            app.CBCT_OSSART_BlockSizeEditField.ValueDisplayFormat = '%.0f';
            app.CBCT_OSSART_BlockSizeEditField.Value = 1;
            app.LambdaLabel = uilabel(app.ConeBeamOSSARTSettingsTab);
            app.LambdaLabel.Text = 'Lambda:';
            app.CBCT_OSSART_LambdaEditField = uieditfield(app.ConeBeamOSSARTSettingsTab, 'numeric');
            app.CBCT_OSSART_LambdaEditField.Limits = [0 Inf];
            app.CBCT_OSSART_LambdaEditField.Value = 1;
            app.LambdaReductionLabel = uilabel(app.ConeBeamOSSARTSettingsTab);
            app.LambdaReductionLabel.Text = 'Lambda Reduction:';
            app.CBCT_OSSART_LambdaReductionEditField = uieditfield(app.ConeBeamOSSARTSettingsTab, 'numeric');
            app.CBCT_OSSART_LambdaReductionEditField.Limits = [0 Inf];
            app.CBCT_OSSART_LambdaReductionEditField.Value = 1;
            app.OrderStrategyLabel = uilabel(app.ConeBeamOSSARTSettingsTab);
            app.OrderStrategyLabel.Text = 'Order Strategy:';
            app.CBCT_OSSART_OrderStrategyDropDown = uidropdown(app.ConeBeamOSSARTSettingsTab);
            app.CBCT_OSSART_OrderStrategyDropDown.Items = {'Angular Distance', 'Cosine', 'Option 3', 'Option 4'};
            app.CBCT_OSSART_OrderStrategyDropDown.Value = 'Angular Distance';
            app.CBCT_OSSART_VerboseCheckBox = uicheckbox(app.ConeBeamOSSARTSettingsTab);
            app.CBCT_OSSART_VerboseCheckBox.Text = 'Verbose Output to Command Line';
            app.CBCT_OSSART_RayRejectionCheckBox = uicheckbox(app.ConeBeamOSSARTSettingsTab);
            app.CBCT_OSSART_RayRejectionCheckBox.Text = 'Allow ray rejection (must have rejection maps)';
            app.ConeBeamOSASDPOCSSettingsTab = uitab(app.ReconstructionAlgorithmSettingsTabGroup);
            app.ConeBeamOSASDPOCSSettingsTab.Title = 'Cone Beam OS-ASD-POCS Settings';
            app.CBCT_OSASDPOCS_VerboseCheckBox = uicheckbox(app.ConeBeamOSASDPOCSSettingsTab);
            app.CBCT_OSASDPOCS_VerboseCheckBox.Text = 'Verbose Output to Command Line';
            app.ForwardProjectionAccuracyLabel_2 = uilabel(app.ConeBeamOSASDPOCSSettingsTab);
            app.ForwardProjectionAccuracyLabel_2.Text = 'Forward Projection Accuracy:';
            app.CBCT_OSASDPOCS_ForwardProjectionAccuracyEditField = uieditfield(app.ConeBeamOSASDPOCSSettingsTab, 'numeric');
            app.CBCT_OSASDPOCS_ForwardProjectionAccuracyEditField.Limits = [0 1];
            app.CBCT_OSASDPOCS_ForwardProjectionAccuracyEditField.Value = 1;
            app.NumberofIterationsLabel_2 = uilabel(app.ConeBeamOSASDPOCSSettingsTab);
            app.NumberofIterationsLabel_2.Text = 'Number of Iterations:';
            app.CBCT_OSASDPOCS_NumberOfIterationsEditField = uieditfield(app.ConeBeamOSASDPOCSSettingsTab, 'numeric');
            app.CBCT_OSASDPOCS_NumberOfIterationsEditField.Limits = [1 Inf];
            app.CBCT_OSASDPOCS_NumberOfIterationsEditField.ValueDisplayFormat = '%.0f';
            app.CBCT_OSASDPOCS_NumberOfIterationsEditField.Value = 1;
            app.BlockSizeLabel_2 = uilabel(app.ConeBeamOSASDPOCSSettingsTab);
            app.BlockSizeLabel_2.Text = 'Block Size:';
            app.CBCT_OSASDPOCS_InitialBlockSizeEditField = uieditfield(app.ConeBeamOSASDPOCSSettingsTab, 'numeric');
            app.CBCT_OSASDPOCS_InitialBlockSizeEditField.Limits = [1 Inf];
            app.CBCT_OSASDPOCS_InitialBlockSizeEditField.ValueDisplayFormat = '%.0f';
            app.CBCT_OSASDPOCS_InitialBlockSizeEditField.Value = 1;
            app.LambdaLabel_2 = uilabel(app.ConeBeamOSASDPOCSSettingsTab);
            app.LambdaLabel_2.Text = 'Lambda:';
            app.CBCT_OSASDPOCS_LambdaEditField = uieditfield(app.ConeBeamOSASDPOCSSettingsTab, 'numeric');
            app.CBCT_OSASDPOCS_LambdaEditField.Limits = [0 Inf];
            app.CBCT_OSASDPOCS_LambdaEditField.Value = 1;
            app.LambdaReductionLabel_2 = uilabel(app.ConeBeamOSASDPOCSSettingsTab);
            app.LambdaReductionLabel_2.Text = 'Lambda Reduction:';
            app.CBCT_OSASDPOCS_LambdaReductionEditField = uieditfield(app.ConeBeamOSASDPOCSSettingsTab, 'numeric');
            app.CBCT_OSASDPOCS_LambdaReductionEditField.Limits = [0 Inf];
            app.CBCT_OSASDPOCS_LambdaReductionEditField.Value = 1;
            app.OrderStrategyLabel_2 = uilabel(app.ConeBeamOSASDPOCSSettingsTab);
            app.OrderStrategyLabel_2.Text = 'Order Strategy:';
            app.CBCT_OSASDPOCS_OrderStrategyDropDown = uidropdown(app.ConeBeamOSASDPOCSSettingsTab);
            app.CBCT_OSASDPOCS_OrderStrategyDropDown.Items = {'Angular Distance', 'Cosine', 'Option 3', 'Option 4'};
            app.CBCT_OSASDPOCS_OrderStrategyDropDown.Value = 'Angular Distance';
            app.NumberofTVIterationsLabel = uilabel(app.ConeBeamOSASDPOCSSettingsTab);
            app.NumberofTVIterationsLabel.Text = 'Number of TV Iterations:';
            app.CBCT_OSASDPOCS_NumberOfTvIterationsEditField = uieditfield(app.ConeBeamOSASDPOCSSettingsTab, 'numeric');
            app.CBCT_OSASDPOCS_NumberOfTvIterationsEditField.Limits = [1 Inf];
            app.CBCT_OSASDPOCS_NumberOfTvIterationsEditField.ValueDisplayFormat = '%.0f';
            app.CBCT_OSASDPOCS_NumberOfTvIterationsEditField.Value = 1;
            app.MaximumL2ErrorRatioLabel = uilabel(app.ConeBeamOSASDPOCSSettingsTab);
            app.MaximumL2ErrorRatioLabel.Text = 'Maximum L2 Error Ratio:';
            app.CBCT_OSASDPOCS_MaxL2ErrorRatioEditField = uieditfield(app.ConeBeamOSASDPOCSSettingsTab, 'numeric');
            app.CBCT_OSASDPOCS_MaxL2ErrorRatioEditField.Limits = [0 1];
            app.CBCT_OSASDPOCS_MaxL2ErrorRatioEditField.Value = 1;
            app.AlphaLabel = uilabel(app.ConeBeamOSASDPOCSSettingsTab);
            app.AlphaLabel.Text = 'Alpha:';
            app.CBCT_OSASDPOCS_AlphaEditField = uieditfield(app.ConeBeamOSASDPOCSSettingsTab, 'numeric');
            app.CBCT_OSASDPOCS_AlphaEditField.Limits = [0 Inf];
            app.CBCT_OSASDPOCS_AlphaEditField.Value = 1;
            app.AlphaReductionLabel = uilabel(app.ConeBeamOSASDPOCSSettingsTab);
            app.AlphaReductionLabel.Text = 'Alpha Reduction:';
            app.CBCT_OSASDPOCS_AlphaReductionEditField = uieditfield(app.ConeBeamOSASDPOCSSettingsTab, 'numeric');
            app.CBCT_OSASDPOCS_AlphaReductionEditField.Limits = [0 Inf];
            app.CBCT_OSASDPOCS_AlphaReductionEditField.Value = 1;
            app.UpdateRatioLabel = uilabel(app.ConeBeamOSASDPOCSSettingsTab);
            app.UpdateRatioLabel.Text = 'Update Ratio:';
            app.CBCT_OSASDPOCS_UpdateRatioEditField = uieditfield(app.ConeBeamOSASDPOCSSettingsTab, 'numeric');
            app.CBCT_OSASDPOCS_UpdateRatioEditField.Limits = [0 1];
            app.CBCT_OSASDPOCS_UpdateRatioEditField.Value = 1;
            app.ConeBeamSARTSettingsTab = uitab(app.ReconstructionAlgorithmSettingsTabGroup);
            app.ConeBeamSARTSettingsTab.Title = 'Cone Beam SART Settings';
            app.CBCT_SART_ForwardProjectionAccuracyLabel = uilabel(app.ConeBeamSARTSettingsTab);
            app.CBCT_SART_ForwardProjectionAccuracyLabel.Text = 'Forward Projection Accuracy:';
            app.CBCT_SART_ForwardProjectionAccuracyEditField = uieditfield(app.ConeBeamSARTSettingsTab, 'numeric');
            app.CBCT_SART_ForwardProjectionAccuracyEditField.Limits = [0 1];
            app.CBCT_SART_ForwardProjectionAccuracyEditField.Value = 1;
            app.CBCT_SART_VerboseCheckBox = uicheckbox(app.ConeBeamSARTSettingsTab);
            app.CBCT_SART_VerboseCheckBox.Text = 'Verbose Output to Command Line';
            app.CBCT_SART_RayRejectionCheckBox = uicheckbox(app.ConeBeamSARTSettingsTab);
            app.CBCT_SART_RayRejectionCheckBox.Text = 'Allow ray rejection (must have rejection maps)';
            app.InitialImageLabel_3 = uilabel(app.ConeBeamSARTSettingsTab);
            app.InitialImageLabel_3.Text = 'Initial Image:';
            app.CBCT_SART_InitialImageDropDown = uidropdown(app.ConeBeamSARTSettingsTab);
            app.CBCT_SART_InitialImageDropDown.Items = {'None', 'Cosine', 'Option 3', 'Option 4'};
            app.CBCT_SART_InitialImageDropDown.Value = 'None';
            app.NumberofIterationsLabel_3 = uilabel(app.ConeBeamSARTSettingsTab);
            app.NumberofIterationsLabel_3.Text = 'Number of Iterations:';
            app.CBCT_SART_NumberOfIterationsEditField = uieditfield(app.ConeBeamSARTSettingsTab, 'numeric');
            app.CBCT_SART_NumberOfIterationsEditField.Limits = [1 Inf];
            app.CBCT_SART_NumberOfIterationsEditField.ValueDisplayFormat = '%.0f';
            app.CBCT_SART_NumberOfIterationsEditField.Value = 1;
            app.LambdaLabel_3 = uilabel(app.ConeBeamSARTSettingsTab);
            app.LambdaLabel_3.Text = 'Lambda:';
            app.CBCT_SART_LambdaEditField = uieditfield(app.ConeBeamSARTSettingsTab, 'numeric');
            app.CBCT_SART_LambdaEditField.Limits = [0 Inf];
            app.CBCT_SART_LambdaEditField.Value = 1;
            app.LambdaReductionLabel_3 = uilabel(app.ConeBeamSARTSettingsTab);
            app.LambdaReductionLabel_3.Text = 'Lambda Reduction:';
            app.CBCT_SART_LambdaReductionEditField = uieditfield(app.ConeBeamSARTSettingsTab, 'numeric');
            app.CBCT_SART_LambdaReductionEditField.Limits = [0 Inf];
            app.CBCT_SART_LambdaReductionEditField.Value = 1;
            app.OrderStrategyLabel_3 = uilabel(app.ConeBeamSARTSettingsTab);
            app.OrderStrategyLabel_3.Text = 'Order Strategy:';
            app.CBCT_SART_OrderStrategyDropDown = uidropdown(app.ConeBeamSARTSettingsTab);
            app.CBCT_SART_OrderStrategyDropDown.Items = {'Angular Distance', 'Cosine', 'Option 3', 'Option 4'};
            app.CBCT_SART_OrderStrategyDropDown.Value = 'Angular Distance';
            app.ConeBeamSIRTSettingsTab = uitab(app.ReconstructionAlgorithmSettingsTabGroup);
            app.ConeBeamSIRTSettingsTab.Title = 'Cone Beam SIRT Settings Tab';
            app.CBCT_SART_ForwardProjectionAccuracyLabel_2 = uilabel(app.ConeBeamSIRTSettingsTab);
            app.CBCT_SART_ForwardProjectionAccuracyLabel_2.Text = 'Forward Projection Accuracy:';
            app.CBCT_SIRT_ForwardProjectionAccuracyEditField = uieditfield(app.ConeBeamSIRTSettingsTab, 'numeric');
            app.CBCT_SIRT_ForwardProjectionAccuracyEditField.Limits = [0 1];
            app.CBCT_SIRT_ForwardProjectionAccuracyEditField.Value = 1;
            app.CBCT_SIRT_VerboseCheckBox = uicheckbox(app.ConeBeamSIRTSettingsTab);
            app.CBCT_SIRT_VerboseCheckBox.Text = 'Verbose Output to Command Line';
            app.InitialImageLabel_4 = uilabel(app.ConeBeamSIRTSettingsTab);
            app.InitialImageLabel_4.Text = 'Initial Image:';
            app.CBCT_SIRT_InitialImageDropDown = uidropdown(app.ConeBeamSIRTSettingsTab);
            app.CBCT_SIRT_InitialImageDropDown.Items = {'None', 'Cosine', 'Option 3', 'Option 4'};
            app.CBCT_SIRT_InitialImageDropDown.Value = 'None';
            app.NumberofIterationsLabel_4 = uilabel(app.ConeBeamSIRTSettingsTab);
            app.NumberofIterationsLabel_4.Text = 'Number of Iterations:';
            app.CBCT_SIRT_NumberOfIterationsEditField = uieditfield(app.ConeBeamSIRTSettingsTab, 'numeric');
            app.CBCT_SIRT_NumberOfIterationsEditField.Limits = [1 Inf];
            app.CBCT_SIRT_NumberOfIterationsEditField.ValueDisplayFormat = '%.0f';
            app.CBCT_SIRT_NumberOfIterationsEditField.Value = 1;
            app.LambdaLabel_4 = uilabel(app.ConeBeamSIRTSettingsTab);
            app.LambdaLabel_4.Text = 'Lambda:';
            app.CBCT_SIRT_LambdaEditField = uieditfield(app.ConeBeamSIRTSettingsTab, 'numeric');
            app.CBCT_SIRT_LambdaEditField.Limits = [0 Inf];
            app.CBCT_SIRT_LambdaEditField.Value = 1;
            app.LambdaReductionLabel_4 = uilabel(app.ConeBeamSIRTSettingsTab);
            app.LambdaReductionLabel_4.Text = 'Lambda Reduction:';
            app.CBCT_SIRT_LambdaReductionEditField = uieditfield(app.ConeBeamSIRTSettingsTab, 'numeric');
            app.CBCT_SIRT_LambdaReductionEditField.Limits = [0 Inf];
            app.CBCT_SIRT_LambdaReductionEditField.Value = 1;
            app.CBCT_SIRT_RayRejectionCheckBox = uicheckbox(app.ConeBeamSIRTSettingsTab);
            app.CBCT_SIRT_RayRejectionCheckBox.Text = 'Allow ray rejection (must have rejection maps)';
            app.ConeBeamSARTTVSettingsTab = uitab(app.ReconstructionAlgorithmSettingsTabGroup);
            app.ConeBeamSARTTVSettingsTab.Title = 'Cone Beam SART-TV Settings Tab';
            app.CBCT_SARTTV_VerboseCheckBox = uicheckbox(app.ConeBeamSARTTVSettingsTab);
            app.CBCT_SARTTV_VerboseCheckBox.Text = 'Verbose Output to Command Line';
            app.CBCT_SARTTV_RayRejectionCheckBox = uicheckbox(app.ConeBeamSARTTVSettingsTab);
            app.CBCT_SARTTV_RayRejectionCheckBox.Text = 'Allow ray rejection (must have rejection maps)';
            app.InitialImageLabel_5 = uilabel(app.ConeBeamSARTTVSettingsTab);
            app.InitialImageLabel_5.Text = 'Initial Image:';
            app.CBCT_SARTTV_InitialImageDropDown = uidropdown(app.ConeBeamSARTTVSettingsTab);
            app.CBCT_SARTTV_InitialImageDropDown.Items = {'None', 'Cosine', 'Option 3', 'Option 4'};
            app.CBCT_SARTTV_InitialImageDropDown.Value = 'None';
            app.ForwardProjectionAccuracyLabel_3 = uilabel(app.ConeBeamSARTTVSettingsTab);
            app.ForwardProjectionAccuracyLabel_3.Text = 'Forward Projection Accuracy:';
            app.CBCT_SARTTV_ForwardProjectionAccuracyEditField = uieditfield(app.ConeBeamSARTTVSettingsTab, 'numeric');
            app.CBCT_SARTTV_ForwardProjectionAccuracyEditField.Limits = [0 1];
            app.CBCT_SARTTV_ForwardProjectionAccuracyEditField.Value = 1;
            app.NumberofIterationsLabel_5 = uilabel(app.ConeBeamSARTTVSettingsTab);
            app.NumberofIterationsLabel_5.Text = 'Number of Iterations:';
            app.CBCT_SARTTV_NumberOfIterationsEditField = uieditfield(app.ConeBeamSARTTVSettingsTab, 'numeric');
            app.CBCT_SARTTV_NumberOfIterationsEditField.Limits = [1 Inf];
            app.CBCT_SARTTV_NumberOfIterationsEditField.ValueDisplayFormat = '%.0f';
            app.CBCT_SARTTV_NumberOfIterationsEditField.Value = 1;
            app.LambdaLabel_5 = uilabel(app.ConeBeamSARTTVSettingsTab);
            app.LambdaLabel_5.Text = 'Lambda:';
            app.CBCT_SARTTV_LambdaEditField = uieditfield(app.ConeBeamSARTTVSettingsTab, 'numeric');
            app.CBCT_SARTTV_LambdaEditField.Limits = [0 Inf];
            app.CBCT_SARTTV_LambdaEditField.Value = 1;
            app.LambdaReductionLabel_5 = uilabel(app.ConeBeamSARTTVSettingsTab);
            app.LambdaReductionLabel_5.Text = 'Lambda Reduction:';
            app.CBCT_SARTTV_LambdaReductionEditField = uieditfield(app.ConeBeamSARTTVSettingsTab, 'numeric');
            app.CBCT_SARTTV_LambdaReductionEditField.Limits = [0 Inf];
            app.CBCT_SARTTV_LambdaReductionEditField.Value = 1;
            app.OrderStrategyLabel_4 = uilabel(app.ConeBeamSARTTVSettingsTab);
            app.OrderStrategyLabel_4.Text = 'Order Strategy:';
            app.CBCT_SARTTV_OrderStrategyDropDown = uidropdown(app.ConeBeamSARTTVSettingsTab);
            app.CBCT_SARTTV_OrderStrategyDropDown.Items = {'Angular Distance', 'Cosine', 'Option 3', 'Option 4'};
            app.CBCT_SARTTV_OrderStrategyDropDown.Value = 'Angular Distance';
            app.NumberofTVIterationsLabel_2 = uilabel(app.ConeBeamSARTTVSettingsTab);
            app.NumberofTVIterationsLabel_2.Text = 'Number of TV Iterations:';
            app.CBCT_SARTTV_NumberOfTvIterationsEditField = uieditfield(app.ConeBeamSARTTVSettingsTab, 'numeric');
            app.CBCT_SARTTV_NumberOfTvIterationsEditField.Limits = [1 Inf];
            app.CBCT_SARTTV_NumberOfTvIterationsEditField.ValueDisplayFormat = '%.0f';
            app.CBCT_SARTTV_NumberOfTvIterationsEditField.Value = 1;
            app.TVLambdaLabel = uilabel(app.ConeBeamSARTTVSettingsTab);
            app.TVLambdaLabel.Text = 'TV Lambda:';
            app.CBCT_SARTTV_TvLambdaEditField = uieditfield(app.ConeBeamSARTTVSettingsTab, 'numeric');
            app.CBCT_SARTTV_TvLambdaEditField.Limits = [0 Inf];
            app.CBCT_SARTTV_TvLambdaEditField.Value = 1;
            app.ConeBeamMLEMSettingsTab = uitab(app.ReconstructionAlgorithmSettingsTabGroup);
            app.ConeBeamMLEMSettingsTab.Title = 'Cone Beam MLEM Settings Tab';
            app.CBCT_MLEM_RayRejectionCheckBox = uicheckbox(app.ConeBeamMLEMSettingsTab);
            app.CBCT_MLEM_RayRejectionCheckBox.Text = 'Allow ray rejection (must have rejection maps)';
            app.ForwardProjectionAccuracyLabel_4 = uilabel(app.ConeBeamMLEMSettingsTab);
            app.ForwardProjectionAccuracyLabel_4.Text = 'Forward Projection Accuracy:';
            app.CBCT_MLEM_ForwardProjectionAccuracyEditField = uieditfield(app.ConeBeamMLEMSettingsTab, 'numeric');
            app.CBCT_MLEM_ForwardProjectionAccuracyEditField.Limits = [0 1];
            app.CBCT_MLEM_ForwardProjectionAccuracyEditField.Value = 1;
            app.NumberofIterationsLabel_6 = uilabel(app.ConeBeamMLEMSettingsTab);
            app.NumberofIterationsLabel_6.Text = 'Number of Iterations:';
            app.CBCT_MLEM_NumberOfIterationsEditField = uieditfield(app.ConeBeamMLEMSettingsTab, 'numeric');
            app.CBCT_MLEM_NumberOfIterationsEditField.Limits = [1 Inf];
            app.CBCT_MLEM_NumberOfIterationsEditField.ValueDisplayFormat = '%.0f';
            app.CBCT_MLEM_NumberOfIterationsEditField.Value = 1;
            app.ConeBeamCGLSSettingsTab = uitab(app.ReconstructionAlgorithmSettingsTabGroup);
            app.ConeBeamCGLSSettingsTab.Title = 'Cone Beam CGLS Settings Tab';
            app.ForwardProjectionAccuracyLabel_5 = uilabel(app.ConeBeamCGLSSettingsTab);
            app.ForwardProjectionAccuracyLabel_5.Text = 'Forward Projection Accuracy:';
            app.CBCT_CGLS_ForwardProjectionAccuracyEditField = uieditfield(app.ConeBeamCGLSSettingsTab, 'numeric');
            app.CBCT_CGLS_ForwardProjectionAccuracyEditField.Limits = [0 1];
            app.CBCT_CGLS_ForwardProjectionAccuracyEditField.Value = 1;
            app.NumberofIterationsLabel_7 = uilabel(app.ConeBeamCGLSSettingsTab);
            app.NumberofIterationsLabel_7.Text = 'Number of Iterations:';
            app.CBCT_CGLS_NumberOfIterationsEditField = uieditfield(app.ConeBeamCGLSSettingsTab, 'numeric');
            app.CBCT_CGLS_NumberOfIterationsEditField.Limits = [1 Inf];
            app.CBCT_CGLS_NumberOfIterationsEditField.ValueDisplayFormat = '%.0f';
            app.CBCT_CGLS_NumberOfIterationsEditField.Value = 1;
            app.CBCT_CGLS_VerboseCheckBox = uicheckbox(app.ConeBeamCGLSSettingsTab);
            app.CBCT_CGLS_VerboseCheckBox.Text = 'Verbose Output to Command Line';
            app.CBCT_CGLS_RayRejectionCheckBox = uicheckbox(app.ConeBeamCGLSSettingsTab);
            app.CBCT_CGLS_RayRejectionCheckBox.Text = 'Allow ray rejection (must have rejection maps)';
            app.InitialImageLabel_6 = uilabel(app.ConeBeamCGLSSettingsTab);
            app.InitialImageLabel_6.Text = 'Initial Image:';
            app.CBCT_CGLS_InitialImageDropDown = uidropdown(app.ConeBeamCGLSSettingsTab);
            app.CBCT_CGLS_InitialImageDropDown.Items = {'None', 'Cosine', 'Option 3', 'Option 4'};
            app.CBCT_CGLS_InitialImageDropDown.Value = 'None';
            app.ConeBeamOSCTVSettingsTab = uitab(app.ReconstructionAlgorithmSettingsTabGroup);
            app.ConeBeamOSCTVSettingsTab.Title = 'Tab';
            app.ForwardProjectionAccuracyLabel_6 = uilabel(app.ConeBeamOSCTVSettingsTab);
            app.ForwardProjectionAccuracyLabel_6.Text = 'Forward Projection Accuracy:';
            app.CBCT_OSCTV_ForwardProjectionAccuracyEditField = uieditfield(app.ConeBeamOSCTVSettingsTab, 'numeric');
            app.CBCT_OSCTV_ForwardProjectionAccuracyEditField.Limits = [0 1];
            app.CBCT_OSCTV_ForwardProjectionAccuracyEditField.Value = 1;
            app.NumberofIterationsLabel_8 = uilabel(app.ConeBeamOSCTVSettingsTab);
            app.NumberofIterationsLabel_8.Text = 'Number of Iterations:';
            app.CBCT_OSCTV_NumberOfIterationsEditField = uieditfield(app.ConeBeamOSCTVSettingsTab, 'numeric');
            app.CBCT_OSCTV_NumberOfIterationsEditField.Limits = [1 Inf];
            app.CBCT_OSCTV_NumberOfIterationsEditField.ValueDisplayFormat = '%.0f';
            app.CBCT_OSCTV_NumberOfIterationsEditField.Value = 1;
            app.InitialBlockSizeLabel_2 = uilabel(app.ConeBeamOSCTVSettingsTab);
            app.InitialBlockSizeLabel_2.Text = 'Initial Block Size:';
            app.CBCT_OSCTV_InitialBlockSizeEditField = uieditfield(app.ConeBeamOSCTVSettingsTab, 'numeric');
            app.CBCT_OSCTV_InitialBlockSizeEditField.Limits = [1 Inf];
            app.CBCT_OSCTV_InitialBlockSizeEditField.ValueDisplayFormat = '%.0f';
            app.CBCT_OSCTV_InitialBlockSizeEditField.Value = 1;
            app.OrderStrategyLabel_5 = uilabel(app.ConeBeamOSCTVSettingsTab);
            app.OrderStrategyLabel_5.Text = 'Order Strategy:';
            app.CBCT_OSCTV_OrderStrategyDropDown = uidropdown(app.ConeBeamOSCTVSettingsTab);
            app.CBCT_OSCTV_OrderStrategyDropDown.Items = {'Angular Distance', 'Cosine', 'Option 3', 'Option 4'};
            app.CBCT_OSCTV_OrderStrategyDropDown.Value = 'Angular Distance';
            app.NumberofTVIterationsLabel_3 = uilabel(app.ConeBeamOSCTVSettingsTab);
            app.NumberofTVIterationsLabel_3.Text = 'Number of TV Iterations:';
            app.CBCT_OSCTV_NumberOfTvIterationsEditField = uieditfield(app.ConeBeamOSCTVSettingsTab, 'numeric');
            app.CBCT_OSCTV_NumberOfTvIterationsEditField.Limits = [1 Inf];
            app.CBCT_OSCTV_NumberOfTvIterationsEditField.ValueDisplayFormat = '%.0f';
            app.CBCT_OSCTV_NumberOfTvIterationsEditField.Value = 1;
            app.cTVstrengthLabel = uilabel(app.ConeBeamOSCTVSettingsTab);
            app.cTVstrengthLabel.Text = 'c (TV strength):';
            app.CBCT_OSCTV_CEditField = uieditfield(app.ConeBeamOSCTVSettingsTab, 'numeric');
            app.CBCT_OSCTV_CEditField.Limits = [0 Inf];
            app.CBCT_OSCTV_CEditField.Value = 1;
            app.FinalBlockSizeLabel_2 = uilabel(app.ConeBeamOSCTVSettingsTab);
            app.FinalBlockSizeLabel_2.Text = 'Final Block Size:';
            app.CBCT_OSCTV_FinalBlockSizeEditField = uieditfield(app.ConeBeamOSCTVSettingsTab, 'numeric');
            app.CBCT_OSCTV_FinalBlockSizeEditField.Limits = [1 Inf];
            app.CBCT_OSCTV_FinalBlockSizeEditField.ValueDisplayFormat = '%.0f';
            app.CBCT_OSCTV_FinalBlockSizeEditField.Value = 1;
            app.BlockSizeReductionPowerLabel_2 = uilabel(app.ConeBeamOSCTVSettingsTab);
            app.BlockSizeReductionPowerLabel_2.Text = 'Block Size Reduction Power:';
            app.CBCT_OSCTV_BlockSizeReductionPowerEditField = uieditfield(app.ConeBeamOSCTVSettingsTab, 'numeric');
            app.CBCT_OSCTV_BlockSizeReductionPowerEditField.Limits = [0 1];
            app.CBCT_OSCTV_BlockSizeReductionPowerEditField.Value = 0.5;
            app.NotesTextArea_2Label = uilabel(app.ReconstructionRunSettingsPanel);
            app.NotesTextArea_2Label.Text = 'Notes:';
            app.ReconstructionRunNotesTextArea = uitextarea(app.ReconstructionRunSettingsPanel);
            app.ReconstructionRunNotesTextArea.Value = {'Notes notes notes'; 'More notes notes notes'; ''};
            app.ReconstructionRunPerformanceButtonGroup = uibuttongroup(app.ReconstructionRunSettingsPanel);
            app.ReconstructionRunPerformanceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ReconstructionRunPerformanceButtonGroupSelectionChanged, true);
            app.ReconstructionRunPerformanceButtonGroup.Title = 'Run Performance';
            app.ReconstructionRunHighPerformanceButton = uiradiobutton(app.ReconstructionRunPerformanceButtonGroup);
            app.ReconstructionRunHighPerformanceButton.Text = 'High Performance';
            app.ReconstructionRunHighPerformanceButton.Value = true;
            app.ReconstructionRunHighWithMultipleCPUsButton = uiradiobutton(app.ReconstructionRunPerformanceButtonGroup);
            app.ReconstructionRunHighWithMultipleCPUsButton.Text = 'High with Multiple CPUs';
            app.ReconstructionRunHighWithGPUButton = uiradiobutton(app.ReconstructionRunPerformanceButtonGroup);
            app.ReconstructionRunHighWithGPUButton.Enable = 'off';
            app.ReconstructionRunHighWithGPUButton.Text = 'High with GPU';
            app.ReconstructionRunSavePathEditField = uieditfield(app.ReconstructionRunSettingsPanel, 'text');
            app.ReconstructionRunSavePathEditField.Editable = 'off';
            app.ReconstructionRunSavePathEditField.Value = 'Simulation Save Path Setting';
            app.zLabel_2 = uilabel(app.ReconstructionRunSettingsPanel);
            app.zLabel_2.Text = 'z:';
            app.ReconstructionRunSliceDimsZEditField = uieditfield(app.ReconstructionRunSettingsPanel, 'numeric');
            app.ReconstructionRunSliceDimsZEditField.Limits = [0 Inf];
            app.ReconstructionRunSliceDimsZEditField.ValueDisplayFormat = '%.0f';
            app.ReconstructionRunSliceDimsZEditField.Value = 1;
            app.zLabel = uilabel(app.ReconstructionRunSettingsPanel);
            app.zLabel.Text = 'z:';
            app.ReconstructionRunSlicePixelDimsZEditField = uieditfield(app.ReconstructionRunSettingsPanel, 'numeric');
            app.ReconstructionRunSlicePixelDimsZEditField.Limits = [0 Inf];
            app.ReconstructionRunSlicePixelDimsZEditField.ValueDisplayFormat = '%11.10g';
            app.ReconstructionRunSlicePixelDimsZEditField.Value = 0.025;
            app.DetectorPixelDimensionsforProcessingmmLabel = uilabel(app.ReconstructionRunSettingsPanel);
            app.DetectorPixelDimensionsforProcessingmmLabel.Text = 'Detector Pixel Dimensions for Processing [mm]:';
            app.xyEditField_4Label_3 = uilabel(app.ReconstructionRunSettingsPanel);
            app.xyEditField_4Label_3.Text = 'x/y:';
            app.ReconstructionRunPixelDimsXYEditField = uieditfield(app.ReconstructionRunSettingsPanel, 'numeric');
            app.ReconstructionRunPixelDimsXYEditField.Limits = [0 Inf];
            app.ReconstructionRunPixelDimsXYEditField.ValueDisplayFormat = '%11.10g';
            app.ReconstructionRunPixelDimsXYEditField.Value = 0.025;
            app.zEditField_6Label_3 = uilabel(app.ReconstructionRunSettingsPanel);
            app.zEditField_6Label_3.Text = 'z:';
            app.ReconstructionRunPixelDimsZEditField = uieditfield(app.ReconstructionRunSettingsPanel, 'numeric');
            app.ReconstructionRunPixelDimsZEditField.Limits = [0 Inf];
            app.ReconstructionRunPixelDimsZEditField.ValueDisplayFormat = '%11.10g';
            app.ReconstructionRunPixelDimsZEditField.Value = 0.025;
            app.ReconstructionRunUseRayExclusionCheckBox = uicheckbox(app.ReconstructionRunSettingsPanel);
            app.ReconstructionRunUseRayExclusionCheckBox.Text = 'Use Ray Exclusion Maps';
            app.ReconstructionRunInterpolateDetectorDataCheckBox = uicheckbox(app.ReconstructionRunSettingsPanel);
            app.ReconstructionRunInterpolateDetectorDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @ReconstructionRunInterpolateDetectorDataCheckBoxValueChanged, true);
            app.ReconstructionRunInterpolateDetectorDataCheckBox.Text = 'Interpolate Detector Data';
            app.WholeDetectorDimensionsforProcessingLabel = uilabel(app.ReconstructionRunSettingsPanel);
            app.WholeDetectorDimensionsforProcessingLabel.Text = 'Whole Detector Dimensions for Processing[#]:';
            app.xyEditField_3Label_3 = uilabel(app.ReconstructionRunSettingsPanel);
            app.xyEditField_3Label_3.Text = 'x/y:';
            app.ReconstructionRunWholeDetectorDimsXYEditField = uieditfield(app.ReconstructionRunSettingsPanel, 'numeric');
            app.ReconstructionRunWholeDetectorDimsXYEditField.Limits = [0 Inf];
            app.ReconstructionRunWholeDetectorDimsXYEditField.ValueDisplayFormat = '%.0f';
            app.ReconstructionRunWholeDetectorDimsXYEditField.Value = 1;
            app.zEditField_5Label_3 = uilabel(app.ReconstructionRunSettingsPanel);
            app.zEditField_5Label_3.Text = 'z:';
            app.ReconstructionRunWholeDetectorDimsZEditField = uieditfield(app.ReconstructionRunSettingsPanel, 'numeric');
            app.ReconstructionRunWholeDetectorDimsZEditField.Limits = [0 Inf];
            app.ReconstructionRunWholeDetectorDimsZEditField.ValueDisplayFormat = '%.0f';
            app.ReconstructionRunWholeDetectorDimsZEditField.Value = 1;
            app.ScanSimulationViewerTab = uitab(app.TabGroup);
            app.ScanSimulationViewerTab.Title = 'Scan Simulation Viewer';
            app.SimulationViewerAxes = uiaxes(app.ScanSimulationViewerTab);
            app.SimulationViewerFilePathLabel = uilabel(app.ScanSimulationViewerTab);
            app.SimulationViewerFilePathLabel.Text = 'Simulation Run File Path';
            app.SimulationViewerLoadButton = uibutton(app.ScanSimulationViewerTab, 'push');
            app.SimulationViewerLoadButton.ButtonPushedFcn = createCallbackFcn(app, @SimulationViewerLoadButtonPushed, true);
            app.SimulationViewerLoadButton.Text = 'Load';
            app.ComputerArchitectureSummaryTextAreaLabel_2 = uilabel(app.ScanSimulationViewerTab);
            app.ComputerArchitectureSummaryTextAreaLabel_2.Text = 'Computer Architecture Summary:';
            app.SimulationViewerInfoComputerArchitectureSummaryTextArea = uitextarea(app.ScanSimulationViewerTab);
            app.SimulationViewerInfoComputerArchitectureSummaryTextArea.Editable = 'off';
            app.SimulationViewerInfoComputerArchitectureSummaryTextArea.Value = {'Computer Information:'; 'System Name: Bravo'; 'CPU Clock Speed: 34GHz'; 'CPU Cache Size: 54Mb'; '# CPUS Cores: 8'; 'OS: Windows 7 (v3.4.5)'; 'User Memory: 4.54Gb'; 'System Memory: 6.78Gb'; ''; 'GPU Information:'; 'No GPU found'};
            app.NotesTextArea_3Label_2 = uilabel(app.ScanSimulationViewerTab);
            app.NotesTextArea_3Label_2.Text = 'Notes:';
            app.SimulationViewerInfoNotesTextArea = uitextarea(app.ScanSimulationViewerTab);
            app.SimulationViewerInfoNotesTextArea.Editable = 'off';
            app.SimulationViewerInfoNotesTextArea.Value = {'Notes notes'; 'ntoes'; 'notes'; 'notes'; 'notes'; ''};
            app.PerAngleTranslationPositionLabel = uilabel(app.ScanSimulationViewerTab);
            app.PerAngleTranslationPositionLabel.Text = 'Per Angle Translation Position:';
            app.SimulationViewerPerAngleTranslationPositionListBox = uilistbox(app.ScanSimulationViewerTab);
            app.SimulationViewerPerAngleTranslationPositionListBox.Items = {'Position (1,1)', 'Position (1,2)', 'Position (1,3)', 'Position (1,4)'};
            app.SimulationViewerPerAngleTranslationPositionListBox.ValueChangedFcn = createCallbackFcn(app, @SimulationViewerPerAngleTranslationPositionListBoxValueChanged, true);
            app.SimulationViewerPerAngleTranslationPositionListBox.Value = 'Position (1,1)';
            app.ScanAngleListBoxLabel = uilabel(app.ScanSimulationViewerTab);
            app.ScanAngleListBoxLabel.Text = 'Scan Angle:';
            app.SimulationViewerScanAngleListBox = uilistbox(app.ScanSimulationViewerTab);
            app.SimulationViewerScanAngleListBox.Items = {'0°', '45°', '60°', '90°'};
            app.SimulationViewerScanAngleListBox.ValueChangedFcn = createCallbackFcn(app, @SimulationViewerScanAngleListBoxValueChanged, true);
            app.SimulationViewerScanAngleListBox.Value = '0°';
            app.SliceListBoxLabel = uilabel(app.ScanSimulationViewerTab);
            app.SliceListBoxLabel.Text = 'Slice:';
            app.SimulationViewerSliceListBox = uilistbox(app.ScanSimulationViewerTab);
            app.SimulationViewerSliceListBox.Items = {'Slice 1', 'Slice 2', 'Slice 3', 'Slice 4'};
            app.SimulationViewerSliceListBox.ValueChangedFcn = createCallbackFcn(app, @SimulationViewerSliceListBoxValueChanged, true);
            app.SimulationViewerSliceListBox.Value = 'Slice 1';
            app.StartDateTimeEditFieldLabel_2 = uilabel(app.ScanSimulationViewerTab);
            app.StartDateTimeEditFieldLabel_2.Text = 'Start Date/Time:';
            app.SimulationViewerInfoStartDateTimeEditField = uieditfield(app.ScanSimulationViewerTab, 'text');
            app.SimulationViewerInfoStartDateTimeEditField.Editable = 'off';
            app.SimulationViewerInfoStartDateTimeEditField.Value = 'Jan 15, 2023   24:25:64';
            app.RunTimeEditFieldLabel_2 = uilabel(app.ScanSimulationViewerTab);
            app.RunTimeEditFieldLabel_2.Text = 'Run Time:';
            app.SimulationViewerInfoRunTimeEditField = uieditfield(app.ScanSimulationViewerTab, 'text');
            app.SimulationViewerInfoRunTimeEditField.Editable = 'off';
            app.SimulationViewerInfoRunTimeEditField.Value = '4324:25:64';
            app.RunPerformanceLabel_2 = uilabel(app.ScanSimulationViewerTab);
            app.RunPerformanceLabel_2.Text = 'Run Performance:';
            app.SimulationViewerInfoRunPerformanceEditField = uieditfield(app.ScanSimulationViewerTab, 'text');
            app.SimulationViewerInfoRunPerformanceEditField.Editable = 'off';
            app.SimulationViewerInfoRunPerformanceEditField.Value = 'High with Multiple CPUs';
            app.GyrfalconVersionEditFieldLabel_3 = uilabel(app.ScanSimulationViewerTab);
            app.GyrfalconVersionEditFieldLabel_3.Text = 'Gyrfalcon Version:';
            app.SimulationViewerInfoGyrfalconVersionEditField = uieditfield(app.ScanSimulationViewerTab, 'text');
            app.SimulationViewerInfoGyrfalconVersionEditField.Editable = 'off';
            app.SimulationViewerInfoGyrfalconVersionEditField.Value = 'v1.0.1';
            app.InvertImageDisplayCheckBox = uicheckbox(app.ScanSimulationViewerTab);
            app.InvertImageDisplayCheckBox.ValueChangedFcn = createCallbackFcn(app, @InvertImageDisplayCheckBoxValueChanged, true);
            app.InvertImageDisplayCheckBox.Text = 'Invert Image Display';
            app.LowEditFieldLabel = uilabel(app.ScanSimulationViewerTab);
            app.LowEditFieldLabel.Text = 'Low:';
            app.ImageContrastLowEditField = uieditfield(app.ScanSimulationViewerTab, 'numeric');
            app.ImageContrastLowEditField.ValueChangedFcn = createCallbackFcn(app, @ImageContrastLowEditFieldValueChanged, true);
            app.HighEditFieldLabel = uilabel(app.ScanSimulationViewerTab);
            app.HighEditFieldLabel.Text = 'High:';
            app.ImageContrastHighEditField = uieditfield(app.ScanSimulationViewerTab, 'numeric');
            app.ImageContrastHighEditField.ValueChangedFcn = createCallbackFcn(app, @ImageContrastHighEditFieldValueChanged, true);
            app.ContrastBoundsLabel = uilabel(app.ScanSimulationViewerTab);
            app.ContrastBoundsLabel.Text = 'Contrast Bounds:';
            app.SimulationViewerLoopThroughAnglesButton = uibutton(app.ScanSimulationViewerTab, 'state');
            app.SimulationViewerLoopThroughAnglesButton.ValueChangedFcn = createCallbackFcn(app, @SimulationViewerLoopThroughAnglesButtonValueChanged, true);
            app.SimulationViewerLoopThroughAnglesButton.Text = 'Loop Through Angles';
            app.ImagetoDisplayButtonGroup = uibuttongroup(app.ScanSimulationViewerTab);
            app.ImagetoDisplayButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ImagetoDisplayButtonGroupSelectionChanged, true);
            app.ImagetoDisplayButtonGroup.Title = 'Image to Display';
            app.I_0Button = uiradiobutton(app.ImagetoDisplayButtonGroup);
            app.I_0Button.Text = 'I_0';
            app.IButton = uiradiobutton(app.ImagetoDisplayButtonGroup);
            app.IButton.Text = 'I';
            app.I_0IButton = uiradiobutton(app.ImagetoDisplayButtonGroup);
            app.I_0IButton.Text = 'I_0 / I';
            app.I_0IButton.Value = true;
            app.lnI_0IButton = uiradiobutton(app.ImagetoDisplayButtonGroup);
            app.lnI_0IButton.Text = 'ln( I_0 / I )';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.DefaultSimulationSaveFolderEditFieldLabel = uilabel(app.SettingsTab);
            app.DefaultSimulationSaveFolderEditFieldLabel.Text = 'Default Simulation Save Folder:';
            app.SimulationSavePathSettingEditField = uieditfield(app.SettingsTab, 'text');
            app.SimulationSavePathSettingEditField.Editable = 'off';
            app.SimulationSavePathSettingEditField.Value = 'Simulation Save Path Setting';
            app.SimulationSavePathSettingChangeButton = uibutton(app.SettingsTab, 'push');
            app.SimulationSavePathSettingChangeButton.ButtonPushedFcn = createCallbackFcn(app, @SimulationSavePathSettingChangeButtonPushed, true);
            app.SimulationSavePathSettingChangeButton.Text = 'Change';
            app.DefaultSimulationReconstructionRunSaveFolderEditFieldLabel = uilabel(app.SettingsTab);
            app.DefaultSimulationReconstructionRunSaveFolderEditFieldLabel.Text = 'Default Simulation/Reconstruction Run Save Folder:';
            app.SimulationReconstructionRunSavePathSettingEditField = uieditfield(app.SettingsTab, 'text');
            app.SimulationReconstructionRunSavePathSettingEditField.Editable = 'off';
            app.SimulationReconstructionRunSavePathSettingEditField.Value = 'Simulation Reconstruction Run Save Path Setting';
            app.SimulationReconstructionRunSavePathSettingChangeButton = uibutton(app.SettingsTab, 'push');
            app.SimulationReconstructionRunSavePathSettingChangeButton.ButtonPushedFcn = createCallbackFcn(app, @SimulationReconstructionRunSavePathSettingChangeButtonPushed, true);
            app.SimulationReconstructionRunSavePathSettingChangeButton.Text = 'Change';
            app.DefaultImagingScanRunSaveFolderLabel = uilabel(app.SettingsTab);
            app.DefaultImagingScanRunSaveFolderLabel.Text = 'Default Imaging Scan Run Save Folder:';
            app.ImagingScanRunSavePathSettingEditField = uieditfield(app.SettingsTab, 'text');
            app.ImagingScanRunSavePathSettingEditField.Editable = 'off';
            app.ImagingScanRunSavePathSettingEditField.Value = 'Imaging Scan Run Save Path Setting';
            app.ImagingScanRunSavePathSettingChangeButton = uibutton(app.SettingsTab, 'push');
            app.ImagingScanRunSavePathSettingChangeButton.ButtonPushedFcn = createCallbackFcn(app, @ImagingScanRunSavePathSettingChangeButtonPushed, true);
            app.ImagingScanRunSavePathSettingChangeButton.Text = 'Change';
            app.DefaultImagingScanImportFolderLabel = uilabel(app.SettingsTab);
            app.DefaultImagingScanImportFolderLabel.Text = 'Default Imaging Scan Import Folder:';
            app.ImagingScanImportPathSettingEditField = uieditfield(app.SettingsTab, 'text');
            app.ImagingScanImportPathSettingEditField.Editable = 'off';
            app.ImagingScanImportPathSettingEditField.Value = 'Imaging Scan Path Setting';
            app.ImagingScanImportPathSettingChangeButton = uibutton(app.SettingsTab, 'push');
            app.ImagingScanImportPathSettingChangeButton.ButtonPushedFcn = createCallbackFcn(app, @ImagingScanImportPathSettingChangeButtonPushed, true);
            app.ImagingScanImportPathSettingChangeButton.Text = 'Change';
            app.DefaultReconstructionPreComputedDataFolderLabel = uilabel(app.SettingsTab);
            app.DefaultReconstructionPreComputedDataFolderLabel.Text = 'Default Reconstruction Pre-Computed Data Folder:';
            app.ReconstructionPreComputedDataPathSettingEditField = uieditfield(app.SettingsTab, 'text');
            app.ReconstructionPreComputedDataPathSettingEditField.Editable = 'off';
            app.ReconstructionPreComputedDataPathSettingEditField.Value = 'Reconstruction Pre-Computed Data Path Setting';
            app.ReconstructionPreComputedDataPathSettingChangeButton = uibutton(app.SettingsTab, 'push');
            app.ReconstructionPreComputedDataPathSettingChangeButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstructionPreComputedDataPathSettingChangeButtonPushed, true);
            app.ReconstructionPreComputedDataPathSettingChangeButton.Text = 'Change';
            app.ImportImagingScanTab = uitab(app.TabGroup);
            app.ImportImagingScanTab.Title = 'Import Imaging Scan';

            app.ImportImagingScanDataButton = uibutton(app.ImportImagingScanTab, 'push');
            app.ImportImagingScanDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportImagingScanDataButtonPushed, true);
            app.ImportImagingScanDataButton.Text = 'Import Scan Data';
            app.ImagingScanDataImportPathChangeButton = uibutton(app.ImportImagingScanTab, 'push');
            app.ImagingScanDataImportPathChangeButton.ButtonPushedFcn = createCallbackFcn(app, @ImagingScanDataImportPathChangeButtonPushed, true);
            app.ImagingScanDataImportPathChangeButton.Text = 'Change';
            app.ImagingScanDataImportPathEditField = uieditfield(app.ImportImagingScanTab, 'text');
            app.ImagingScanDataImportPathEditField.Editable = 'off';
            app.ImagingScanDataImportPathEditField.Value = 'Simulation Save Path Setting';
            app.DefaultSimulationSaveFolderEditFieldLabel_2 = uilabel(app.ImportImagingScanTab);
            app.DefaultSimulationSaveFolderEditFieldLabel_2.Text = 'Imaging Scan Data Import Folder:';
            app.ImagingScanRunSavePathChangeButton = uibutton(app.ImportImagingScanTab, 'push');
            app.ImagingScanRunSavePathChangeButton.ButtonPushedFcn = createCallbackFcn(app, @ImagingScanRunSavePathChangeButtonPushed, true);
            app.ImagingScanRunSavePathChangeButton.Text = 'Change';
            app.ImagingScanRunSavePathEditField = uieditfield(app.ImportImagingScanTab, 'text');
            app.ImagingScanRunSavePathEditField.Editable = 'off';
            app.ImagingScanRunSavePathEditField.Value = 'Simulation Save Path Setting';
            app.DefaultSimulationSaveFolderEditFieldLabel_3 = uilabel(app.ImportImagingScanTab);
            app.DefaultSimulationSaveFolderEditFieldLabel_3.Text = 'Imported Imaging Scan Run Save Folder:';
            app.NotesTextArea_2Label_2 = uilabel(app.ImportImagingScanTab);
            app.NotesTextArea_2Label_2.Text = 'Notes:';
            app.ImagingScanRunNotesTextArea = uitextarea(app.ImportImagingScanTab);
            app.ImagingScanRunNotesTextArea.Value = {'Notes notes notes'; 'More notes notes notes'; ''};
            app.ImagingScanTypeLabel = uilabel(app.ImportImagingScanTab);
            app.ImagingScanTypeLabel.Text = 'Imaging Scan Type:';
            app.ImagingScanTypeDropDown = uidropdown(app.ImportImagingScanTab);
            app.ImagingScanTypeDropDown.Items = {'Optical CT', 'X-Ray CT'};
            app.ImagingScanTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ImagingScanTypeDropDownValueChanged, true);
            app.ImagingScanTypeDropDown.Value = 'Optical CT';
            app.ImportImagingScanTabGroup = uitabgroup(app.ImportImagingScanTab);
            app.OpticalCTImportSettingsTab = uitab(app.ImportImagingScanTabGroup);
            app.OpticalCTImportSettingsTab.Title = 'Optical CT Import Settings';
            app.LightSourceColourLabel = uilabel(app.OpticalCTImportSettingsTab);
            app.LightSourceColourLabel.Text = 'Light Source Colour:';
            app.OptCtSettings_LightSourceColourDropDown = uidropdown(app.OpticalCTImportSettingsTab);
            app.OptCtSettings_LightSourceColourDropDown.Items = {'Amber (585nm)', 'Red (650nm)'};
            app.OptCtSettings_LightSourceColourDropDown.Value = 'Amber (585nm)';
            app.XRayCTImportSettingsTab = uitab(app.ImportImagingScanTabGroup);
            app.XRayCTImportSettingsTab.Title = 'X-Ray CT Import Settings';
            app.GyrfalconLabel = uilabel(app.GyrfalconUIFigure);
            app.GyrfalconLabel.Text = 'Gyrfalcon:';
            app.ComputedTomographyScanSimulatorLabel = uilabel(app.GyrfalconUIFigure);
            app.ComputedTomographyScanSimulatorLabel.Text = 'Computed Tomography Scan Simulator';
            app.ImageReconstructionToolboxLabel = uilabel(app.GyrfalconUIFigure);
            app.ImageReconstructionToolboxLabel.Text = '& Image Reconstruction Toolbox';
            app.StatusOuputTextAreaLabel = uilabel(app.GyrfalconUIFigure);
            app.StatusOuputTextAreaLabel.Text = 'Status Output:';
            app.StatusOutputTextArea = uitextarea(app.GyrfalconUIFigure);
            app.StatusOutputTextArea.Editable = 'off';
            app.StatusOutputTextArea.Value = {'Loading Data...Test'; 'Alpha 1/2...Done'; 'Test'; 'Test'; 'Test'};
            app.StatusOutputLamp = uilamp(app.GyrfalconUIFigure);
            app.OpenWorkspaceButton = uibutton(app.GyrfalconUIFigure, 'push');
            app.OpenWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @OpenWorkspaceButtonPushed, true);
            app.OpenWorkspaceButton.Text = 'Open Workspace';
            app.SaveWorkspaceButton = uibutton(app.GyrfalconUIFigure, 'push');
            app.SaveWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @SaveWorkspaceButtonPushed, true);
            app.SaveWorkspaceButton.Text = 'Save Workspace';
            app.Label = uilabel(app.GyrfalconUIFigure);
            app.Label.Text = '______________________________________________________________';
            app.DavidADeVriesBCSdaviddevriesqueensucaLabel = uilabel(app.GyrfalconUIFigure);
            app.DavidADeVriesBCSdaviddevriesqueensucaLabel.Text = 'David A. DeVries, B.CS     (david.devries@queensu.ca)';
            app.QueensUniversityKingstonONCanadaLabel = uilabel(app.GyrfalconUIFigure);
            app.QueensUniversityKingstonONCanadaLabel.Text = 'Queen''s University (Kingston, ON, Canada)';
            app.KingstonGeneralHospitalCancerCentreofSoutheasternOntarioLabel = uilabel(app.GyrfalconUIFigure);
            app.KingstonGeneralHospitalCancerCentreofSoutheasternOntarioLabel.Text = 'Kingston General Hospital / Cancer Centre of Southeastern Ontario';
            app.Label_7 = uilabel(app.GyrfalconUIFigure);
            app.Label_7.Text = '______________________________________________________________';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GyrfalconApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GyrfalconUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GyrfalconUIFigure)
        end
    end
end